classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatusLabel                     matlab.ui.control.Label
        AdrianComisso2025Label          matlab.ui.control.Label
        Version320250322Label           matlab.ui.control.Label
        CarletonHybridRocketMotorSimulatorLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SimulationSetupTab              matlab.ui.container.Tab
        SaveConfigurationButton         matlab.ui.control.Button
        LoadConfigurationButton         matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        MassPropertiesMeasureFromNozzlePanel  matlab.ui.container.Panel
        IncludeCenterofMassSimulationCheckBox  matlab.ui.control.CheckBox
        DistancetoBottomofFuelGrainEditField  matlab.ui.control.NumericEditField
        DistancetoBottomofFuelGrainEditFieldLabel  matlab.ui.control.Label
        DropDown_37                     matlab.ui.control.DropDown
        TankInnerDiameterEditField      matlab.ui.control.NumericEditField
        TankInnerDiameterEditFieldLabel  matlab.ui.control.Label
        DropDown_36                     matlab.ui.control.DropDown
        DistancetoBottomofTankEditField  matlab.ui.control.NumericEditField
        DistancetoBottomofTankEditFieldLabel  matlab.ui.control.Label
        DropDown_35                     matlab.ui.control.DropDown
        DistancetoMotorDryCGEditField   matlab.ui.control.NumericEditField
        DistancetoMotorDryCGEditFieldLabel  matlab.ui.control.Label
        DropDown_34                     matlab.ui.control.DropDown
        MotorDryMassEditField           matlab.ui.control.NumericEditField
        MotorDryMassEditFieldLabel      matlab.ui.control.Label
        DropDown_33                     matlab.ui.control.DropDown
        NozzlePropertiesPanel_2         matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        NozzleDischargeCoefficientEditField_2  matlab.ui.control.NumericEditField
        NozzleDischargeCoefficientEditField_2Label  matlab.ui.control.Label
        NozzleEfficiencyEditField_2     matlab.ui.control.NumericEditField
        NozzleEfficiencyEditField_2Label  matlab.ui.control.Label
        NozzleExpansionRatioEditField_2  matlab.ui.control.NumericEditField
        NozzleExpansionRatioEditField_2Label  matlab.ui.control.Label
        NozzleThroatDiameterEditField_2  matlab.ui.control.NumericEditField
        NozzleThroatDiameterEditField_2Label  matlab.ui.control.Label
        DropDown_29                     matlab.ui.control.DropDown
        ConicalNozzleHalfAngleEditField_2  matlab.ui.control.NumericEditField
        ConicalNozzleHalfAngleLabel_2   matlab.ui.control.Label
        NozzleModelDropDown_2           matlab.ui.control.DropDown
        NozzleModelDropDown_2Label      matlab.ui.control.Label
        ChamberPropertiesPanel_2        matlab.ui.container.Panel
        NoFileSelectedLabel             matlab.ui.control.Label
        LoadFuelConfigButton            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        LengthExponentmEditField_2      matlab.ui.control.NumericEditField
        LengthExponentmEditField_2Label  matlab.ui.control.Label
        RegressionExponentnEditField_2  matlab.ui.control.NumericEditField
        RegressionExponentnEditField_2Label  matlab.ui.control.Label
        RegressionCoefficientaEditField_2  matlab.ui.control.NumericEditField
        RegressionCoefficientaEditField_2Label  matlab.ui.control.Label
        ConstantOFRatioEditField_2      matlab.ui.control.NumericEditField
        ConstantOFRatioEditField_2Label  matlab.ui.control.Label
        FuelDensityEditField_2          matlab.ui.control.NumericEditField
        FuelDensityEditField_2Label     matlab.ui.control.Label
        DropDown_23                     matlab.ui.control.DropDown
        CombustionEfficiencyCEditField_2  matlab.ui.control.NumericEditField
        CombustionEfficiencyCEditField_2Label  matlab.ui.control.Label
        FuelGrainLenghtEditField        matlab.ui.control.NumericEditField
        FuelGrainLenghtEditFieldLabel   matlab.ui.control.Label
        DropDown_21                     matlab.ui.control.DropDown
        FuelGrainOuterDiameterEditField  matlab.ui.control.NumericEditField
        FuelGrainOuterDiameterEditFieldLabel  matlab.ui.control.Label
        DropDown_20                     matlab.ui.control.DropDown
        FuelGrainInnerDiameterEditField  matlab.ui.control.NumericEditField
        FuelGrainInnerDiameterEditFieldLabel  matlab.ui.control.Label
        DropDown_19                     matlab.ui.control.DropDown
        InitialChamberPressureEditField  matlab.ui.control.NumericEditField
        InitialChamberPressureEditFieldLabel  matlab.ui.control.Label
        DropDown_18                     matlab.ui.control.DropDown
        EmptyChamberVolumeEditField_2   matlab.ui.control.NumericEditField
        EmptyChamberVolumeEditField_2Label  matlab.ui.control.Label
        DropDown_17                     matlab.ui.control.DropDown
        RegressionModelDropDown_2       matlab.ui.control.DropDown
        RegressionModelDropDown_2Label  matlab.ui.control.Label
        InjectorPropertiesPanel_2       matlab.ui.container.Panel
        InjectorDischargeCoefficientEditField_2  matlab.ui.control.NumericEditField
        InjectorDischargeCoefficientEditField_2Label  matlab.ui.control.Label
        InjectorDiameterEditField       matlab.ui.control.NumericEditField
        InjectorDiameterEditFieldLabel  matlab.ui.control.Label
        DropDown_15                     matlab.ui.control.DropDown
        NumberofInjectorOrificesEditField_2  matlab.ui.control.NumericEditField
        NumberofInjectorOrificesEditField_2Label  matlab.ui.control.Label
        InjectorVaporModelDropDown      matlab.ui.control.DropDown
        InjectorVaporModelDropDownLabel  matlab.ui.control.Label
        InjectorLiquidModelDropDown     matlab.ui.control.DropDown
        InjectorLiquidModelDropDownLabel  matlab.ui.control.Label
        VentPropertiesPanel             matlab.ui.container.Panel
        VentDischargeCoefficientEditField  matlab.ui.control.NumericEditField
        VentDischargeCoefficientEditFieldLabel  matlab.ui.control.Label
        VentDiameterEditField_2         matlab.ui.control.NumericEditField
        VentDiameterEditField_2Label    matlab.ui.control.Label
        DropDown_12                     matlab.ui.control.DropDown
        VentModelDropDown_2             matlab.ui.control.DropDown
        VentModelDropDown_2Label        matlab.ui.control.Label
        VentStateDropDown               matlab.ui.control.DropDown
        VentStateDropDownLabel          matlab.ui.control.Label
        ConfigurationNameEditField      matlab.ui.control.EditField
        ConfigurationNameEditFieldLabel  matlab.ui.control.Label
        TankPropertiesPanel             matlab.ui.container.Panel
        OxidizerTemperatureEditField    matlab.ui.control.NumericEditField
        OxidizerTemperatureEditFieldLabel  matlab.ui.control.Label
        DropDown_38                     matlab.ui.control.DropDown
        OxidizerMassEditField_2         matlab.ui.control.NumericEditField
        OxidizerMassEditField_2Label    matlab.ui.control.Label
        DropDown_10                     matlab.ui.control.DropDown
        TankVolumeEditField_2           matlab.ui.control.NumericEditField
        TankVolumeEditField_2Label      matlab.ui.control.Label
        DropDown_9                      matlab.ui.control.DropDown
        OxidizerDropDown_2              matlab.ui.control.DropDown
        OxidizerDropDown_2Label         matlab.ui.control.Label
        ThermodymenicTankModelDropDown  matlab.ui.control.DropDown
        ThermodymenicTankModelDropDownLabel  matlab.ui.control.Label
        AtmosphericPropertiesPanel      matlab.ui.container.Panel
        AmbientAirDensityEditField      matlab.ui.control.NumericEditField
        AmbientAirDensityEditFieldLabel  matlab.ui.control.Label
        DropDown_8                      matlab.ui.control.DropDown
        AmbientTemperatureEditField     matlab.ui.control.NumericEditField
        AmbientTemperatureEditFieldLabel  matlab.ui.control.Label
        DropDown_7                      matlab.ui.control.DropDown
        AmbientPressureEditField        matlab.ui.control.NumericEditField
        AmbientPressureEditFieldLabel   matlab.ui.control.Label
        DropDown_6                      matlab.ui.control.DropDown
        SimulationParametersPanel       matlab.ui.container.Panel
        sLabel_2                        matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        SimulationTimeStepsEditField    matlab.ui.control.NumericEditField
        SimulationTimeStepsEditFieldLabel  matlab.ui.control.Label
        SimulationTypeDropDown          matlab.ui.control.DropDown
        SimulationTypeDropDownLabel     matlab.ui.control.Label
        MaximumSimulationTimeEditField  matlab.ui.control.NumericEditField
        MaximumSimulationTimeEditFieldLabel  matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        PerformanceSummaryTextArea      matlab.ui.control.TextArea
        PerformanceSummaryTextAreaLabel  matlab.ui.control.Label
        SaveResultsButton               matlab.ui.control.Button
        ExportCSVButton                 matlab.ui.control.Button
        ExportRSEButton                 matlab.ui.control.Button
        SavePlotButton                  matlab.ui.control.Button
        ClearPlotButton                 matlab.ui.control.Button
        AddPlotButton                   matlab.ui.control.Button
        LegendNameEditField             matlab.ui.control.EditField
        LegendNameEditFieldLabel        matlab.ui.control.Label
        YaxisunitsDropDown              matlab.ui.control.DropDown
        YaxisunitsDropDownLabel         matlab.ui.control.Label
        XaxisunitsDropDown              matlab.ui.control.DropDown
        XaxisunitsDropDownLabel         matlab.ui.control.Label
        YaxisDropDown                   matlab.ui.control.DropDown
        YaxisLabel                      matlab.ui.control.Label
        XaxisDropDown                   matlab.ui.control.DropDown
        XaxisDropDownLabel              matlab.ui.control.Label
        PlotTitleEditField              matlab.ui.control.EditField
        PlotTitleEditFieldLabel         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        InfoTab                         matlab.ui.container.Tab
        ThissimulatorwasdesignedLabel   matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        app_dir % stores app directory
        fuel_path % stores propellant path
        fuel_file % stores propellant file
        output % stores sim output
        plt % stores plot data
        u % stores units for plot 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.app_dir = mfilename('fullpath');
            app.app_dir = app.app_dir(1:end-4);
        end

        % Value changed function: IncludeCenterofMassSimulationCheckBox
        function IncludeCenterofMassSimulationCheckBoxValueChanged(app, event)
            value = app.IncludeCenterofMassSimulationCheckBox.Value;
            if value == true
                app.MotorDryMassEditField.Enable = "on";
                app.MotorDryMassEditFieldLabel.Enable ="on";
                app.DropDown_33.Enable ="on";
                app.DistancetoMotorDryCGEditField.Enable ="on";
                app.DistancetoMotorDryCGEditFieldLabel.Enable ="on";
                app.DropDown_34.Enable ="on";
                app.DistancetoBottomofTankEditField.Enable ="on";
                app.DistancetoBottomofTankEditFieldLabel.Enable ="on";
                app.DropDown_35.Enable ="on";
                app.TankInnerDiameterEditField.Enable ="on";
                app.TankInnerDiameterEditFieldLabel.Enable ="on";
                app.DropDown_36.Enable ="on";
                app.DistancetoBottomofFuelGrainEditField.Enable ="on";
                app.DistancetoBottomofFuelGrainEditFieldLabel.Enable ="on";
                app.DropDown_37.Enable ="on";
            else %value = false
                app.MotorDryMassEditField.Enable ="off";
                app.MotorDryMassEditFieldLabel.Enable ="off";
                app.DropDown_33.Enable ="off";
                app.DistancetoMotorDryCGEditField.Enable ="off";
                app.DistancetoMotorDryCGEditFieldLabel.Enable ="off";
                app.DropDown_34.Enable ="off";
                app.DistancetoBottomofTankEditField.Enable ="off";
                app.DistancetoBottomofTankEditFieldLabel.Enable ="off";
                app.DropDown_35.Enable ="off";
                app.TankInnerDiameterEditField.Enable ="off";
                app.TankInnerDiameterEditFieldLabel.Enable ="off";
                app.DropDown_36.Enable ="off";
                app.DistancetoBottomofFuelGrainEditField.Enable ="off";
                app.DistancetoBottomofFuelGrainEditFieldLabel.Enable ="off";
                app.DropDown_37.Enable ="off";
            end
        end

        % Value changed function: NozzleModelDropDown_2
        function NozzleModelDropDown_2ValueChanged(app, event)
            value = app.NozzleModelDropDown_2.Value;
            if strcmp(value,'1D')
                app.ConicalNozzleHalfAngleEditField_2.Enable = "off";
                app.degLabel.Enable = "off";
                app.ConicalNozzleHalfAngleLabel_2.Enable = "off";
            else %value == 2D
                app.ConicalNozzleHalfAngleEditField_2.Enable = "on";
                app.degLabel.Enable = "on";
                app.ConicalNozzleHalfAngleLabel_2.Enable = "on";
            end
        end

        % Value changed function: RegressionModelDropDown_2
        function RegressionModelDropDown_2ValueChanged(app, event)
            value = app.RegressionModelDropDown_2.Value;
            if strcmp(value,'Constant OF')
                app.ConstantOFRatioEditField_2.Enable = "on";
                app.ConstantOFRatioEditField_2Label.Enable = "on";
                app.RegressionCoefficientaEditField_2.Enable = "off";
                app.RegressionCoefficientaEditField_2Label.Enable = "off";
                app.RegressionExponentnEditField_2.Enable = "off";
                app.RegressionExponentnEditField_2Label.Enable = "off";
                app.LengthExponentmEditField_2.Enable = "off";
                app.LengthExponentmEditField_2Label.Enable = "off";
            else %value = Shifting OF
                app.ConstantOFRatioEditField_2.Enable = "off";
                app.ConstantOFRatioEditField_2Label.Enable = "off";
                app.RegressionCoefficientaEditField_2.Enable = "on";
                app.RegressionCoefficientaEditField_2Label.Enable = "on";
                app.RegressionExponentnEditField_2.Enable = "on";
                app.RegressionExponentnEditField_2Label.Enable = "on";
                app.LengthExponentmEditField_2.Enable = "on";
                app.LengthExponentmEditField_2Label.Enable = "on";
            end
        end

        % Value changed function: VentStateDropDown
        function VentStateDropDownValueChanged(app, event)
            value = app.VentStateDropDown.Value;
            if strcmp(value,'No Vent')
                app.VentModelDropDown_2.Enable = "off";
                app.VentModelDropDown_2Label.Enable = "off";
                app.VentDiameterEditField_2.Enable = "off";
                app.VentDiameterEditField_2Label.Enable = "off";
                app.DropDown_12.Enable = "off";
                app.VentDischargeCoefficientEditField.Enable = "off";
                app.VentDischargeCoefficientEditFieldLabel.Enable = "off";
            else %Value = Vent to Atmosphere or Vent to Chamber
                app.VentModelDropDown_2.Enable = "on";
                app.VentModelDropDown_2Label.Enable = "on";
                app.VentDiameterEditField_2.Enable = "on";
                app.VentDiameterEditField_2Label.Enable = "on";
                app.DropDown_12.Enable = "on";
                app.VentDischargeCoefficientEditField.Enable = "on";
                app.VentDischargeCoefficientEditFieldLabel.Enable = "on";
            end
        end

        % Value changed function: SimulationTypeDropDown
        function SimulationTypeDropDownValueChanged(app, event)
            value = app.SimulationTypeDropDown.Value;
            if strcmp(value, 'Cold Flow')
                app.ChamberPropertiesPanel_2.Enable = "off";
                app.NozzlePropertiesPanel_2.Enable = "off";
                app.MassPropertiesMeasureFromNozzlePanel.Enable = "off";
            else
                app.ChamberPropertiesPanel_2.Enable = "on";
                app.NozzlePropertiesPanel_2.Enable = "on";
                app.MassPropertiesMeasureFromNozzlePanel.Enable = "on";
            end
        end

        % Button pushed function: LoadFuelConfigButton
        function LoadFuelConfigButtonPushed(app, event)
            [app.fuel_file,app.fuel_path,~] = uigetfile('*.mat','Select a propellant file',sprintf('%s\\Fuel Configs',app.app_dir));

            if isequal(app.fuel_file,0)
            else
                app.NoFileSelectedLabel.Text = app.fuel_file;
                app.StatusLabel.Text = 'Status: Fuel Config Loaded';
            end
        end

        % Button pushed function: SaveConfigurationButton
        function SaveConfigurationButtonPushed(app, event)
            % Config Name
            cfg.mtr_nm = app.ConfigurationNameEditField.Value;
            
            % Simulation Parameters
            cfg.t_max = app.MaximumSimulationTimeEditField.Value;
            cfg.dt = app.SimulationTimeStepsEditField.Value;
            cfg.cold_flow = app.SimulationTypeDropDown.Value;
            
            % Atmospheric Properties
            cfg.P_atm = app.AmbientPressureEditField.Value;
            cfg.P_atm_u = app.DropDown_6.Value;
            cfg.T_atm = app.AmbientTemperatureEditField.Value;
            cfg.T_atm_u = app.DropDown_7.Value;
            cfg.rho_atm = app.AmbientAirDensityEditField.Value;
            cfg.rho_atm_u = app.DropDown_8.Value;
            
            % Tank Properties
            cfg.model_tank = app.ThermodymenicTankModelDropDown.Value;
            cfg.ox_name = app.OxidizerDropDown_2.Value;
            cfg.V_tank = app.TankVolumeEditField_2.Value;
            cfg.V_tank_u = app.DropDown_9.Value;
            cfg.m_ox_total = app.OxidizerMassEditField_2.Value;
            cfg.m_ox_total_u = app.DropDown_10.Value;
            cfg.T_tank = app.OxidizerTemperatureEditField.Value;
            cfg.T_tank_u = app.DropDown_38.Value;
            
            % Vent Properties
            cfg.state_vent = app.VentStateDropDown.Value;
            cfg.model_vent = app.VentModelDropDown_2.Value;
            cfg.d_vent = app.VentDiameterEditField_2.Value;
            cfg.d_vent_u = app.DropDown_12.Value;
            cfg.Cd_vent = app.VentDischargeCoefficientEditField.Value;

            % Injector Properties
            cfg.model_inj_l = app.InjectorLiquidModelDropDown.Value;
            cfg.model_inj_v = app.InjectorVaporModelDropDown.Value;
            cfg.N_inj = app.NumberofInjectorOrificesEditField_2.Value;
            cfg.d_inj = app.InjectorDiameterEditField.Value;
            cfg.d_inj_u = app.DropDown_15.Value;
            cfg.Cd_inj = app.InjectorDischargeCoefficientEditField_2.Value;

            % Chamber Properties
            cfg.model_reg = app.RegressionModelDropDown_2.Value;
            cfg.V_cmbr_empty = app.EmptyChamberVolumeEditField_2.Value;
            cfg.V_cmbr_empty_u = app.DropDown_17.Value;
            cfg.P_cmbr = app.InitialChamberPressureEditField.Value;
            cfg.P_cmbr_u = app.DropDown_18.Value;
            cfg.id_grn = app.FuelGrainInnerDiameterEditField.Value;
            cfg.id_grn_u = app.DropDown_19.Value;
            cfg.od_grn = app.FuelGrainOuterDiameterEditField.Value;
            cfg.od_grn_u = app.DropDown_20.Value;
            cfg.l_grn = app.FuelGrainLenghtEditField.Value;
            cfg.l_grn_u = app.DropDown_21.Value;
            cfg.c_star_eff = app.CombustionEfficiencyCEditField_2.Value;
            cfg.rho_fuel = app.FuelDensityEditField_2.Value;
            cfg.rho_fuel_u = app.DropDown_23.Value;
            cfg.const_OF = app.ConstantOFRatioEditField_2.Value;
            cfg.a = app.RegressionCoefficientaEditField_2.Value;
            cfg.n = app.RegressionExponentnEditField_2.Value;
            cfg.m = app.LengthExponentmEditField_2.Value;

            % Propellant Config
            cfg.fuel_file = app.fuel_file;
            cfg.fuel_path = app.fuel_path;
            
            % Nozzle Properties
            cfg.model_noz = app.NozzleModelDropDown_2.Value;
            cfg.alpha_noz = app.ConicalNozzleHalfAngleEditField_2.Value;
            cfg.d_noz_throat = app.NozzleThroatDiameterEditField_2.Value;
            cfg.d_noz_throat_u = app.DropDown_29.Value;
            cfg.exp_ratio_noz = app.NozzleExpansionRatioEditField_2.Value;
            cfg.eff_noz = app.NozzleEfficiencyEditField_2.Value;
            cfg.Cd_noz = app.NozzleDischargeCoefficientEditField_2.Value;
            
            % Mass Properties
            cfg.mass_sim = app.IncludeCenterofMassSimulationCheckBox.Value;
            cfg.m_dry_motor = app.MotorDryMassEditField.Value;
            cfg.m_dry_motor_u = app.DropDown_33.Value;
            cfg.cg_dry_motor = app.DistancetoMotorDryCGEditField.Value;
            cfg.cg_dry_motor_u = app.DropDown_34.Value;
            cfg.l_tank_bottom = app.DistancetoBottomofTankEditField.Value;
            cfg.l_tank_bottom_u = app.DropDown_35.Value;
            cfg.d_tank = app.TankInnerDiameterEditField.Value;
            cfg.d_tank_u = app.DropDown_36.Value;
            cfg.l_grn_bottom = app.DistancetoBottomofFuelGrainEditField.Value;
            cfg.l_grn_bottom_u = app.DropDown_37.Value;
            
            % choose fole loaction
            [file,path] = uiputfile('*.mat','Save Motor Configuration',sprintf('%s\\User Saved Configs\\%s.mat',app.app_dir,cfg.mtr_nm));
            
            %save file location
            if isequal(file,0)
            else
                filename = convertCharsToStrings(fullfile(path,file));
                save(filename,'cfg');
                fclose('all');
            end

            %notify
                app.StatusLabel.Text = 'Status: User Config Saved';
        end

        % Button pushed function: LoadConfigurationButton
        function LoadConfigurationButtonPushed(app, event)
            [file,path,~] = uigetfile('*.mat','Select a configuration file',sprintf('%s\\User Saved Configs',app.app_dir));
            
            if isequal(file,0)
            else
                load(fullfile(path, file),'cfg');
                
                % Config Name
                app.ConfigurationNameEditField.Value = cfg.mtr_nm;
                
                % Simulation Parameters
                app.MaximumSimulationTimeEditField.Value = cfg.t_max;
                app.SimulationTimeStepsEditField.Value = cfg.dt;
                app.SimulationTypeDropDown.Value = cfg.cold_flow;
                
                % Atmospheric Properties
                app.AmbientPressureEditField.Value = cfg.P_atm;
                app.DropDown_6.Value = cfg.P_atm_u;
                app.AmbientTemperatureEditField.Value = cfg.T_atm;
                app.DropDown_7.Value = cfg.T_atm_u;
                app.AmbientAirDensityEditField.Value = cfg.rho_atm;
                app.DropDown_8.Value = cfg.rho_atm_u;
                
                % Tank Properties
                app.ThermodymenicTankModelDropDown.Value = cfg.model_tank;
                app.OxidizerDropDown_2.Value = cfg.ox_name;
                app.TankVolumeEditField_2.Value = cfg.V_tank;
                app.DropDown_9.Value = cfg.V_tank_u;
                app.OxidizerMassEditField_2.Value = cfg.m_ox_total;
                app.DropDown_10.Value = cfg.m_ox_total_u;
                app.OxidizerTemperatureEditField.Value = cfg.T_tank;
                app.DropDown_38.Value = cfg.T_tank_u;
                
                % Vent Properties
                app.VentStateDropDown.Value = cfg.state_vent;
                app.VentModelDropDown_2.Value = cfg.model_vent;
                app.VentDiameterEditField_2.Value = cfg.d_vent;
                app.DropDown_12.Value = cfg.d_vent_u;
                app.VentDischargeCoefficientEditField.Value = cfg.Cd_vent;
            
                % Injector Properties
                app.InjectorLiquidModelDropDown.Value = cfg.model_inj_l;
                app.InjectorVaporModelDropDown.Value = cfg.model_inj_v;
                app.NumberofInjectorOrificesEditField_2.Value = cfg.N_inj;
                app.InjectorDiameterEditField.Value = cfg.d_inj;
                app.DropDown_15.Value = cfg.d_inj_u;
                app.InjectorDischargeCoefficientEditField_2.Value = cfg.Cd_inj;
            
                % Chamber Properties
                app.RegressionModelDropDown_2.Value = cfg.model_reg;
                app.EmptyChamberVolumeEditField_2.Value = cfg.V_cmbr_empty;
                app.DropDown_17.Value = cfg.V_cmbr_empty_u;
                app.InitialChamberPressureEditField.Value = cfg.P_cmbr;
                app.DropDown_18.Value = cfg.P_cmbr_u;
                app.FuelGrainInnerDiameterEditField.Value = cfg.id_grn;
                app.DropDown_19.Value = cfg.id_grn_u;
                app.FuelGrainOuterDiameterEditField.Value = cfg.od_grn;
                app.DropDown_20.Value = cfg.od_grn_u;
                app.FuelGrainLenghtEditField.Value = cfg.l_grn;
                app.DropDown_21.Value = cfg.l_grn_u;
                app.CombustionEfficiencyCEditField_2.Value = cfg.c_star_eff;
                app.FuelDensityEditField_2.Value = cfg.rho_fuel;
                app.DropDown_23.Value = cfg.rho_fuel_u;
                app.ConstantOFRatioEditField_2.Value = cfg.const_OF;
                app.RegressionCoefficientaEditField_2.Value = cfg.a;
                app.RegressionExponentnEditField_2.Value = cfg.n;
                app.LengthExponentmEditField_2.Value = cfg.m;
                
                % Propellant Config
                app.fuel_file = cfg.fuel_file;
                app.fuel_path = cfg.fuel_path;

                % Nozzle Properties
                app.NozzleModelDropDown_2.Value = cfg.model_noz;
                app.ConicalNozzleHalfAngleEditField_2.Value = cfg.alpha_noz;
                app.NozzleThroatDiameterEditField_2.Value = cfg.d_noz_throat;
                app.DropDown_29.Value = cfg.d_noz_throat_u;
                app.NozzleExpansionRatioEditField_2.Value = cfg.exp_ratio_noz;
                app.NozzleEfficiencyEditField_2.Value = cfg.eff_noz;
                app.NozzleDischargeCoefficientEditField_2.Value = cfg.Cd_noz;
                
                % Mass Properties
                app.IncludeCenterofMassSimulationCheckBox.Value = cfg.mass_sim;
                app.MotorDryMassEditField.Value = cfg.m_dry_motor;
                app.DropDown_33.Value = cfg.m_dry_motor_u;
                app.DistancetoMotorDryCGEditField.Value = cfg.cg_dry_motor;
                app.DropDown_34.Value = cfg.cg_dry_motor_u;
                app.DistancetoBottomofTankEditField.Value = cfg.l_tank_bottom;
                app.DropDown_35.Value = cfg.l_tank_bottom_u;
                app.TankInnerDiameterEditField.Value = cfg.d_tank;
                app.DropDown_36.Value = cfg.d_tank_u;
                app.DistancetoBottomofFuelGrainEditField.Value = cfg.l_grn_bottom;
                app.DropDown_37.Value = cfg.l_grn_bottom_u;
                
                fclose('all');

                % Update fields based on IncludeCenterofMassSimulationCheckBox
                if app.IncludeCenterofMassSimulationCheckBox.Value == true
                    app.MotorDryMassEditField.Enable = "on";
                    app.MotorDryMassEditFieldLabel.Enable = "on";
                    app.DropDown_33.Enable = "on";
                    app.DistancetoMotorDryCGEditField.Enable = "on";
                    app.DistancetoMotorDryCGEditFieldLabel.Enable = "on";
                    app.DropDown_34.Enable = "on";
                    app.DistancetoBottomofTankEditField.Enable = "on";
                    app.DistancetoBottomofTankEditFieldLabel.Enable = "on";
                    app.DropDown_35.Enable = "on";
                    app.TankInnerDiameterEditField.Enable = "on";
                    app.TankInnerDiameterEditFieldLabel.Enable = "on";
                    app.DropDown_36.Enable = "on";
                    app.DistancetoBottomofFuelGrainEditField.Enable = "on";
                    app.DistancetoBottomofFuelGrainEditFieldLabel.Enable = "on";
                    app.DropDown_37.Enable = "on";
                else
                    app.MotorDryMassEditField.Enable = "off";
                    app.MotorDryMassEditFieldLabel.Enable = "off";
                    app.DropDown_33.Enable = "off";
                    app.DistancetoMotorDryCGEditField.Enable = "off";
                    app.DistancetoMotorDryCGEditFieldLabel.Enable = "off";
                    app.DropDown_34.Enable = "off";
                    app.DistancetoBottomofTankEditField.Enable = "off";
                    app.DistancetoBottomofTankEditFieldLabel.Enable = "off";
                    app.DropDown_35.Enable = "off";
                    app.TankInnerDiameterEditField.Enable = "off";
                    app.TankInnerDiameterEditFieldLabel.Enable = "off";
                    app.DropDown_36.Enable = "off";
                    app.DistancetoBottomofFuelGrainEditField.Enable = "off";
                    app.DistancetoBottomofFuelGrainEditFieldLabel.Enable = "off";
                    app.DropDown_37.Enable = "off";
                end
                
                % Update fields based on NozzleModelDropDown_2
                if strcmp(app.NozzleModelDropDown_2.Value, '1D')
                    app.ConicalNozzleHalfAngleEditField_2.Enable = "off";
                    app.degLabel.Enable = "off";
                    app.ConicalNozzleHalfAngleLabel_2.Enable = "off";
                else
                    app.ConicalNozzleHalfAngleEditField_2.Enable = "on";
                    app.degLabel.Enable = "on";
                    app.ConicalNozzleHalfAngleLabel_2.Enable = "on";
                end
                
                % Update fields based on RegressionModelDropDown_2
                if strcmp(app.RegressionModelDropDown_2.Value, 'Constant OF')
                    app.ConstantOFRatioEditField_2.Enable = "on";
                    app.ConstantOFRatioEditField_2Label.Enable = "on";
                    app.RegressionCoefficientaEditField_2.Enable = "off";
                    app.RegressionCoefficientaEditField_2Label.Enable = "off";
                    app.RegressionExponentnEditField_2.Enable = "off";
                    app.RegressionExponentnEditField_2Label.Enable = "off";
                    app.LengthExponentmEditField_2.Enable = "off";
                    app.LengthExponentmEditField_2Label.Enable = "off";
                else
                    app.ConstantOFRatioEditField_2.Enable = "off";
                    app.ConstantOFRatioEditField_2Label.Enable = "off";
                    app.RegressionCoefficientaEditField_2.Enable = "on";
                    app.RegressionCoefficientaEditField_2Label.Enable = "on";
                    app.RegressionExponentnEditField_2.Enable = "on";
                    app.RegressionExponentnEditField_2Label.Enable = "on";
                    app.LengthExponentmEditField_2.Enable = "on";
                    app.LengthExponentmEditField_2Label.Enable = "on";
                end
                
                % Update fields based on VentStateDropDown
                if strcmp(app.VentStateDropDown.Value, 'No Vent')
                    app.VentModelDropDown_2.Enable = "off";
                    app.VentModelDropDown_2Label.Enable = "off";
                    app.VentDiameterEditField_2.Enable = "off";
                    app.VentDiameterEditField_2Label.Enable = "off";
                    app.DropDown_12.Enable = "off";
                    app.VentDischargeCoefficientEditField.Enable = "off";
                    app.VentDischargeCoefficientEditFieldLabel.Enable = "off";
                else
                    app.VentModelDropDown_2.Enable = "on";
                    app.VentModelDropDown_2Label.Enable = "on";
                    app.VentDiameterEditField_2.Enable = "on";
                    app.VentDiameterEditField_2Label.Enable = "on";
                    app.DropDown_12.Enable = "on";
                    app.VentDischargeCoefficientEditField.Enable = "on";
                    app.VentDischargeCoefficientEditFieldLabel.Enable = "on";
                end
                
                % Update fields based on SimulationTypeDropDown
                if strcmp(app.SimulationTypeDropDown.Value, 'Cold Flow')
                    app.ChamberPropertiesPanel_2.Enable = "off";
                    app.NozzlePropertiesPanel_2.Enable = "off";
                    app.MassPropertiesMeasureFromNozzlePanel.Enable = "off";
                else
                    app.ChamberPropertiesPanel_2.Enable = "on";
                    app.NozzlePropertiesPanel_2.Enable = "on";
                    app.MassPropertiesMeasureFromNozzlePanel.Enable = "on";
                end
                
                % Update fuel file feild
                if isequal(app.fuel_file,0) | isempty(app.fuel_file)
                else
                    app.NoFileSelectedLabel.Text = app.fuel_file;
                end

                %notify
                app.StatusLabel.Text = 'Status: User Config Loaded';
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            %notify
            app.StatusLabel.Text = 'Status: Cooking...';
            pause(0.5)
            
            % Simulation Parameters
            state.t_max = app.MaximumSimulationTimeEditField.Value;
            state.dt = app.SimulationTimeStepsEditField.Value;
            if strcmp(app.SimulationTypeDropDown.Value,'Cold Flow')
                state.cold_flow = true;
            elseif strcmp(app.SimulationTypeDropDown.Value,'Hot Fire')
                state.cold_flow = false;
            end

            % Atmospheric Properties
            state.P_atm = Unit_Conversion(app.AmbientPressureEditField.Value, app.DropDown_6.Value, true); % Convert to Pa
            state.T_atm = Unit_Conversion(app.AmbientTemperatureEditField.Value, app.DropDown_7.Value, true); % Convert to K
            state.rho_atm = Unit_Conversion(app.AmbientAirDensityEditField.Value, app.DropDown_8.Value, true); % Convert to kg/m^3
            
            % Tank Properties
            if strcmp(app.ThermodymenicTankModelDropDown.Value,'HRAP (Original)')
                state.model_tank = 0;
            elseif strcmp(app.ThermodymenicTankModelDropDown.Value,'HRAP (Remastered)')
                state.model_tank = 1;
            elseif strcmp(app.ThermodymenicTankModelDropDown.Value,'Internal Energy')
                state.model_tank = 2;
            end
            state.ox_name = app.OxidizerDropDown_2.Value;
            state.V_tank = Unit_Conversion(app.TankVolumeEditField_2.Value, app.DropDown_9.Value, true); % Convert to m^3
            state.m_ox_total = Unit_Conversion(app.OxidizerMassEditField_2.Value, app.DropDown_10.Value, true); % Convert to kg
            state.T_tank = Unit_Conversion(app.OxidizerTemperatureEditField.Value, app.DropDown_38.Value, true); % Convert to K
            
            % Vent Properties
            if strcmp(app.VentStateDropDown.Value,'No Vent')
                state.state_vent = 0;
            elseif strcmp(app.VentStateDropDown.Value,'Vent to Atmosphere')
                state.state_vent = 1;
            elseif strcmp(app.VentStateDropDown.Value,'Vent to Chamber')
                state.state_vent = 2;
            end
            if strcmp(app.VentModelDropDown_2.Value,'Single Phase Incompressible')
                state.model_vent = 'SPI_v';
            elseif strcmp(app.VentModelDropDown_2.Value,'Perfect Gas Compressible')
                state.model_vent = 'PGC_v';
            end
            state.d_vent = Unit_Conversion(app.VentDiameterEditField_2.Value, app.DropDown_12.Value, true); % Convert to m
            state.Cd_vent = app.VentDischargeCoefficientEditField.Value;
        
            % Injector Properties
            if strcmp(app.InjectorLiquidModelDropDown.Value,'Single Phase Incompressible')
                state.model_inj_l = 'SPI';
            elseif strcmp(app.InjectorLiquidModelDropDown.Value,'Homogeneus Equilibrium')
                state.model_inj_l = 'HEM';
            elseif strcmp(app.InjectorLiquidModelDropDown.Value,'Dyer Model')
                state.model_inj_l = 'Dyer';
            end
            if strcmp(app.InjectorVaporModelDropDown.Value,'Single Phase Incompressible')
                state.model_inj_v = 'SPI_v';
            elseif strcmp(app.InjectorVaporModelDropDown.Value,'Perfect Gas Compressible')
                state.model_inj_v = 'PGC_v';
            end
            state.N_inj = app.NumberofInjectorOrificesEditField_2.Value;
            state.d_inj = Unit_Conversion(app.InjectorDiameterEditField.Value, app.DropDown_15.Value, true); % Convert to m
            state.Cd_inj = app.InjectorDischargeCoefficientEditField_2.Value;
        
            % Chamber Properties
            if strcmp(app.RegressionModelDropDown_2.Value,'Constant OF')
                state.model_reg = 'const_OF';
            elseif strcmp(app.RegressionModelDropDown_2.Value,'Shifting OF')
                state.model_reg = 'shift_OF';
            end
            state.V_cmbr_empty = Unit_Conversion(app.EmptyChamberVolumeEditField_2.Value, app.DropDown_17.Value, true); % Convert to m^3
            state.P_cmbr = Unit_Conversion(app.InitialChamberPressureEditField.Value, app.DropDown_18.Value, true); % Convert to Pa
            state.id_grn = Unit_Conversion(app.FuelGrainInnerDiameterEditField.Value, app.DropDown_19.Value, true); % Convert to m
            state.od_grn = Unit_Conversion(app.FuelGrainOuterDiameterEditField.Value, app.DropDown_20.Value, true); % Convert to m
            state.l_grn = Unit_Conversion(app.FuelGrainLenghtEditField.Value, app.DropDown_21.Value, true); % Convert to m
            state.c_star_eff = Unit_Conversion(app.CombustionEfficiencyCEditField_2.Value,'%',true); %Convert to dec
            state.rho_fuel = Unit_Conversion(app.FuelDensityEditField_2.Value, app.DropDown_23.Value, true); % Convert to kg/m^3
            
            % Specific to regression model
            if strcmp(app.RegressionModelDropDown_2.Value, 'Constant OF')
                state.const_OF = app.ConstantOFRatioEditField_2.Value;
                % Set default values for other regression parameters
                state.a = 0;
                state.n = 0;
                state.m = 0;
            else % 'Shifting OF'
                state.a = app.RegressionCoefficientaEditField_2.Value;
                state.n = app.RegressionExponentnEditField_2.Value;
                state.m = app.LengthExponentmEditField_2.Value;
                % Set default value for const_OF
                state.const_OF = 0;
            end
            
            % Nozzle Properties
            state.model_noz = app.NozzleModelDropDown_2.Value;
            state.alpha_noz = app.ConicalNozzleHalfAngleEditField_2.Value; % Already in degrees
            state.d_noz_throat = Unit_Conversion(app.NozzleThroatDiameterEditField_2.Value, app.DropDown_29.Value, true); % Convert to m
            state.exp_ratio_noz = app.NozzleExpansionRatioEditField_2.Value;
            state.eff_noz = Unit_Conversion(app.NozzleEfficiencyEditField_2.Value,'%',true); %Convert to dec
            state.Cd_noz = app.NozzleDischargeCoefficientEditField_2.Value;
            
            % Mass Properties (only if enabled)
            state.mass_sim = app.IncludeCenterofMassSimulationCheckBox.Value;
            
            if state.mass_sim
                state.m_dry_motor = Unit_Conversion(app.MotorDryMassEditField.Value, app.DropDown_33.Value, true); % Convert to kg
                state.cg_dry_motor = Unit_Conversion(app.DistancetoMotorDryCGEditField.Value, app.DropDown_34.Value, true); % Convert to m
                state.l_tank_bottom = Unit_Conversion(app.DistancetoBottomofTankEditField.Value, app.DropDown_35.Value, true); % Convert to m
                state.d_tank = Unit_Conversion(app.TankInnerDiameterEditField.Value, app.DropDown_36.Value, true); % Convert to m
                state.l_grn_bottom = Unit_Conversion(app.DistancetoBottomofFuelGrainEditField.Value, app.DropDown_37.Value, true); % Convert to m
            end

            %call main script
            if isequal(app.fuel_file,0) | isempty(app.fuel_file) 
                app.StatusLabel.Text = 'Error: no fuel config was loaded';
            else
                fuel = importdata(fullfile(app.fuel_path, app.fuel_file));
                [app.output] = Main(state,fuel);
                app.StatusLabel.Text = 'Status: Sim Complete';
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Sim Results
            
            totalImpulse = trapz(app.output.t(app.output.F_rocket>0),app.output.F_rocket(app.output.F_rocket>0));

            [motorClass,percent] = impulse(totalImpulse);
            
            %Display Motor Performance
            
            burnTime = app.output.t(sum(app.output.F_rocket>0));
            peakF_thr = max(app.output.F_rocket);
            averageF_thr = mean(app.output.F_rocket(app.output.F_rocket>0));
            peakPressure = max(app.output.P_cmbr)/10^5;
            averagePressure = mean(app.output.P_cmbr(app.output.P_cmbr>0))/10^5;
            fuelConsumed = (app.output.m_fuel(1)-app.output.m_fuel(end));
            oxidizerConsumed = (app.output.m_ox_total(1)-app.output.m_ox_total(end));
            averageOF = oxidizerConsumed/fuelConsumed;
            intPressure = trapz(app.output.t,app.output.P_cmbr);
            Cstar = intPressure*0.25*pi*state.d_noz_throat^2/(fuelConsumed+oxidizerConsumed);
            specificImpulse = totalImpulse/(((oxidizerConsumed+fuelConsumed))*9.81);
            fuelLeft = app.output.id_grn(end)*100;
            
            app.PerformanceSummaryTextArea.Value = sprintf(['Motor Name: %s\n ' ...
                                                 '   Propellant: %s\n ' ...
                                                 '   Oxidizer Tank Volume: %0.0f cc\n ' ...
                                                 '   Burn Time: %0.3f s\n ' ...
                                                 '   Peak Thrust: %0.1f N\n ' ...
                                                 '   Average Thrust: %0.1f N\n ' ...
                                                 '   Total Impulse: %0.2f N-s\n ' ...
                                                 '   Peak Chamber Pressure: %0.3f bar\n ' ...
                                                 '   Average Chamber Pressure: %0.3f bar\n ' ...
                                                 '   Port Diameter at Burnout: %0.3f cm\n ' ...
                                                 '   Fuel Consumed: %0.3f kg\n ' ...
                                                 '   Oxidizer Consumed: %0.3f kg\n ' ...
                                                 '   Average OF Ratio: %0.3f\n ' ...
                                                 '   Characteristic Velocity: %0.1f m/s\n ' ...
                                                 '   Specific Impulse: %0.1f s\n ' ...
                                                 '   Motor Classification: %3.0f%% %s%0.0f'], ...
                                                 app.ConfigurationNameEditField.Value, ...
                                                 fuel.prop_nm, ...
                                                 state.V_tank*10^6, ...
                                                 burnTime,peakF_thr, ...
                                                 averageF_thr, ...
                                                 totalImpulse, ...
                                                 peakPressure, ...
                                                 averagePressure, ...
                                                 fuelLeft, ...
                                                 fuelConsumed, ...
                                                 oxidizerConsumed, ...
                                                 averageOF, ...
                                                 Cstar, ...
                                                 specificImpulse, ...
                                                 percent, ...
                                                 motorClass, ...
                                                 averageF_thr);
            
            app.PlotTitleEditField.Enable = 1;
            app.PlotTitleEditFieldLabel.Enable = 1;
            app.XaxisDropDown.Enable = 1;
            app.XaxisDropDownLabel.Enable = 1;
            app.YaxisDropDown.Enable = 1;
            app.YaxisLabel.Enable = 1;
            app.XaxisunitsDropDown.Enable = 1;
            app.XaxisunitsDropDownLabel.Enable = 1;
            app.YaxisunitsDropDown.Enable = 1;
            app.YaxisunitsDropDownLabel.Enable = 1;
            app.LegendNameEditField.Enable = 1;
            app.LegendNameEditFieldLabel.Enable = 1;
            app.AddPlotButton.Enable = 1;
            app.ClearPlotButton.Enable = 1;
            app.SavePlotButton.Enable = 1;
            app.SaveResultsButton.Enable = 1;
            if app.IncludeCenterofMassSimulationCheckBox.Value == 1
                app.ExportRSEButton.Enable = 1;
            end
            app.ExportCSVButton.Enable = 1;
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            value = app.XaxisDropDown.Value;
            
            if value == "Time"
                app.XaxisunitsDropDown.Items = {'s'};
            elseif value == "Oxidizer Mass"
                app.XaxisunitsDropDown.Items = {'lbm','kg','g','oz'};
            elseif value == "Fuel Mass"
                app.XaxisunitsDropDown.Items = {'lbm','kg','g','oz'};
            elseif value == "Total Motor Mass"
                app.XaxisunitsDropDown.Items = {'lbm','kg','g','oz'};
            elseif value == "Center of Mass"
                app.XaxisunitsDropDown.Items = {'in','ft','mm','cm','m'};
            elseif value == "Chamber Pressure"
                app.XaxisunitsDropDown.Items = {'psi','psf','atm','Pa','kPa','Bar','MPa'};
            elseif value == "Total Propellant Mass"
                app.XaxisunitsDropDown.Items = {'lbm','kg','g','oz'};
            elseif value == "Tank Pressure"
                app.XaxisunitsDropDown.Items = {'psi','psf','atm','Pa','kPa','Bar','MPa'};
            elseif value == "Oxidizer Mass Flow Rate"
                app.XaxisunitsDropDown.Items = {'lbm/s','kg/s','g/s','oz/s'};
            elseif value == "Fuel Mass Flow Rate"
                app.XaxisunitsDropDown.Items = {'lbm/s','kg/s','g/s','oz/s'};
            elseif value == "Total Mass Flow Rate"
                app.XaxisunitsDropDown.Items = {'lbm/s','kg/s','g/s','oz/s'};
            elseif value == "OF Ratio"
                app.XaxisunitsDropDown.Items = {''};
            elseif value == "Regression Rate"
                app.XaxisunitsDropDown.Items = {'in/s','ft/s','mm/s','cm/s','m/s'};
            elseif value == "Thrust"
                app.XaxisunitsDropDown.Items = {'N','lbf','kgf'};
            elseif value == "Injector Pressure Drop"
                app.XaxisunitsDropDown.Items = {'psi','psf','atm','Pa','kPa','Bar','MPa'};
            end
        end

        % Value changed function: YaxisDropDown
        function YaxisDropDownValueChanged(app, event)
            value = app.YaxisDropDown.Value;
            if value == "Time"
                app.YaxisunitsDropDown.Items = {'s'};
                app.LegendNameEditField.Value = 'Time';
            elseif value == "Oxidizer Mass"
                app.YaxisunitsDropDown.Items = {'lbm','kg','g','oz'};
                app.LegendNameEditField.Value = 'Oxidizer Mass';
            elseif value == "Fuel Mass"
                app.YaxisunitsDropDown.Items = {'lbm','kg','g','oz'};
                app.LegendNameEditField.Value = 'Fuel Mass';
            elseif value == "Total Motor Mass"
                app.YaxisunitsDropDown.Items = {'lbm','kg','g','oz'};
                app.LegendNameEditField.Value = 'Total Motor Mass';
            elseif value == "Center of Mass"
                app.YaxisunitsDropDown.Items = {'in','ft','mm','cm','m'};
                app.LegendNameEditField.Value = 'Center of Mass';
            elseif value == "Chamber Pressure"
                app.YaxisunitsDropDown.Items = {'psi','psf','atm','Pa','kPa','Bar','MPa'};
                app.LegendNameEditField.Value = 'Chamber Pressure';
            elseif value == "Total Propellant Mass"
                app.YaxisunitsDropDown.Items = {'lbm','kg','g','oz'};
                app.LegendNameEditField.Value = 'Total Propellant Mass';
            elseif value == "Tank Pressure"
                app.YaxisunitsDropDown.Items = {'psi','psf','atm','Pa','kPa','Bar','MPa'};
                app.LegendNameEditField.Value = 'Tank Pressure';
            elseif value == "Oxidizer Mass Flow Rate"
                app.YaxisunitsDropDown.Items = {'lbm/s','kg/s','g/s','oz/s'};
                app.LegendNameEditField.Value = 'Oxidizer Mass Flow Rate';
            elseif value == "Fuel Mass Flow Rate"
                app.YaxisunitsDropDown.Items = {'lbm/s','kg/s','g/s','oz/s'};
                app.LegendNameEditField.Value = 'Fuel Mass Flow Rate';
            elseif value == "Total Mass Flow Rate"
                app.YaxisunitsDropDown.Items = {'lbm/s','kg/s','g/s','oz/s'};
                app.LegendNameEditField.Value = 'Total Mass Flow Rate';
            elseif value == "OF Ratio"
                app.YaxisunitsDropDown.Items = {''};
                app.LegendNameEditField.Value = 'OF Ratio';
            elseif value == "Regression Rate"
                app.YaxisunitsDropDown.Items = {'in/s','ft/s','mm/s','cm/s','m/s'};
                app.LegendNameEditField.Value = 'Regression Rate';
            elseif value == "Thrust"
                app.YaxisunitsDropDown.Items = {'N','lbf','kgf'};
                app.LegendNameEditField.Value = 'Thrust';
            elseif value == "Injector Pressure Drop"
                app.YaxisunitsDropDown.Items = {'psi','psf','atm','Pa','kPa','Bar','MPa'};
                app.LegendNameEditField.Value = 'Injector Pressure Drop';
            end
        end

        % Button pushed function: AddPlotButton
        function AddPlotButtonPushed(app, event)
            value = app.XaxisDropDown.Value;
            
            if value == "Time"
                app.plt.x = app.output.t;
            elseif value == "Oxidizer Mass"
                app.plt.x = app.output.m_ox_total;
            elseif value == "Fuel Mass"
                app.plt.x = app.output.m_fuel;
            elseif value == "Total Motor Mass"
                app.plt.x = app.output.m_motor;
            elseif value == "Center of Mass"
                app.plt.x = app.output.cg_motor;
            elseif value == "Chamber Pressure"
                app.plt.x = app.output.P_cmbr;
            elseif value == "Total Propellant Mass"
                app.plt.x = app.output.m_fuel+app.output.m_ox_total;
            elseif value == "Tank Pressure"
                app.plt.x = app.output.P_tank;
            elseif value == "Oxidizer Mass Flow Rate"
                app.plt.x = app.output.m_dot_inj;
            elseif value == "Fuel Mass Flow Rate"
                app.plt.x = app.output.m_dot_fuel;
            elseif value == "Total Mass Flow Rate"
                app.plt.x = app.output.m_dot_noz;
            elseif value == "OF Ratio"
                app.plt.x = app.output.OF;
            elseif value == "Regression Rate"
                app.plt.x = app.output.r_dot_grn;
            elseif value == "Thrust"
                app.plt.x = app.output.F_rocket;
            elseif value == "Injector Pressure Drop"
                app.plt.x = app.output.P_tank - app.output.P_cmbr;
            end
            
            value = app.YaxisDropDown.Value;
            
            if value == "Time"
                app.plt.y = app.output.t;
            elseif value == "Oxidizer Mass"
                app.plt.y = app.output.m_ox_total;
            elseif value == "Fuel Mass"
                app.plt.y = app.output.m_fuel;
            elseif value == "Total Motor Mass"
                app.plt.y = app.output.m_motor;
            elseif value == "Center of Mass"
                app.plt.y = app.output.cg_motor;
            elseif value == "Chamber Pressure"
                app.plt.y = app.output.P_cmbr;
            elseif value == "Total Propellant Mass"
                app.plt.y = app.output.m_fuel+app.output.m_ox_total;
            elseif value == "Tank Pressure"
                app.plt.y = app.output.P_tank;
            elseif value == "Oxidizer Mass Flow Rate"
                app.plt.y = app.output.m_dot_inj;
            elseif value == "Fuel Mass Flow Rate"
                app.plt.y = app.output.m_dot_fuel;
            elseif value == "Total Mass Flow Rate"
                app.plt.y = app.output.m_dot_noz;
            elseif value == "OF Ratio"
                app.plt.y = app.output.OF;
            elseif value == "Regression Rate"
                app.plt.y = app.output.r_dot_grn;
            elseif value == "Thrust"
                app.plt.y = app.output.F_rocket;
            elseif value == "Injector Pressure Drop"
                app.plt.y = app.output.P_tank - app.output.P_cmbr;
            end
            
            value = app.XaxisunitsDropDown.Value;
            
            if value == "lbm"
            elseif value == "kg"
                app.u.x_axis = 1;
            elseif value == "g"
                app.u.x_axis = 1/0.001;
            elseif value == "oz"
                app.u.x_axis = 1/0.0283495;
            elseif value == "in"
                app.u.x_axis = 1/0.0254;
            elseif value == "ft"
                app.u.x_axis = 1/0.3048;
            elseif value == "mm"
                app.u.x_axis = 1/0.001;
            elseif value == "cm"
                app.u.x_axis = 1/0.01';
            elseif value == "m"
                app.u.x_axis = 1;
            elseif value == "psi"
                app.u.x_axis = 1/6894.76;
            elseif value == "psf"
                app.u.x_axis = 1/147.8802777777778;
            elseif value == "atm"
                app.u.x_axis = 1/101325;
            elseif value == "Pa"
                app.u.x_axis = 1;
            elseif value == "kPa"
                app.u.x_axis = 1/1000;
            elseif value == "Bar"
                app.u.x_axis = 1/100000;
            elseif value == "MPa"
                app.u.x_axis = 1/1000000;
            elseif value == "lbm/s"
                app.u.x_axis = 1/0.453592;
            elseif value == "kg/s"
                app.u.x_axis = 1;
            elseif value == "g/s"
                app.u.x_axis = 1/0.001;
            elseif value == "oz/s"
                app.u.x_axis = 1/0.0283495;
            elseif value == "in/s"
                app.u.x_axis = 1/0.0254;
            elseif value == "ft/s"
                app.u.x_axis = 1/0.3048;
            elseif value == "mm/s"
                app.u.x_axis = 1/0.001;
            elseif value == "cm/s"
                app.u.x_axis = 1/0.01;
            elseif value == "m/s"
                app.u.x_axis = 1;
            elseif value == "N"
                app.u.x_axis = 1;
            elseif value == "lbf"
                app.u.x_axis = 1/4.44822;
            elseif value == "kgf"
                app.u.x_axis = 1/9.80665;
            else
                app.u.x_axis = 1;
            end
            
            value = app.YaxisunitsDropDown.Value;
            
            if value == "lbm"
            elseif value == "kg"
                app.u.y_axis = 1;
            elseif value == "g"
                app.u.y_axis = 1000;
            elseif value == "oz"
                app.u.y_axis = 35.28;
            elseif value == "in"
                app.u.y_axis = 1/0.0254;
            elseif value == "ft"
                app.u.y_axis = 1/0.3048;
            elseif value == "mm"
                app.u.y_axis = 1000;
            elseif value == "cm"
                app.u.y_axis = 100;
            elseif value == "m"
                app.u.y_axis = 1;
            elseif value == "psi"
                app.u.y_axis = 1/6894.76;
            elseif value == "psf"
                app.u.y_axis = 1/147.8802777777778;
            elseif value == "atm"
                app.u.y_axis = 1/101325;
            elseif value == "Pa"
                app.u.y_axis = 1;
            elseif value == "kPa"
                app.u.y_axis = 0.001;
            elseif value == "Bar"
                app.u.y_axis = 0.00001;
            elseif value == "MPa"
                app.u.y_axis = 0.000001;
            elseif value == "lbm/s"
                app.u.y_axis = 1/0.453592;
            elseif value == "kg/s"
                app.u.y_axis = 1;
            elseif value == "g/s"
                app.u.y_axis = 1/0.001;
            elseif value == "oz/s"
                app.u.y_axis = 1/0.0283495;
            elseif value == "in/s"
                app.u.y_axis = 1/0.0254;
            elseif value == "ft/s"
                app.u.y_axis = 1/0.3048;
            elseif value == "mm/s"
                app.u.y_axis = 1/0.001;
            elseif value == "cm/s"
                app.u.y_axis = 1/0.01;
            elseif value == "m/s"
                app.u.y_axis = 1;
            elseif value == "N"
                app.u.y_axis = 1;
            elseif value == "lbf"
                app.u.y_axis = 1/4.44822;
            elseif value == "kgf"
                app.u.y_axis = 1/9.80665;
            else
                app.u.y_axis = 1;
            end
            
            plot(app.UIAxes,(app.plt.x.*app.u.x_axis),(app.plt.y.*app.u.y_axis),'DisplayName',app.LegendNameEditField.Value)
            app.UIAxes.Title.String = app.PlotTitleEditField.Value;
   
            xvalue = app.XaxisDropDown.Value;
            if xvalue == "Time"
                app.plt.xlabel = append('Time (',app.XaxisunitsDropDown.Value,')');
            elseif xvalue == "Oxidizer Mass"
                app.plt.xlabel = append('Mass (',app.XaxisunitsDropDown.Value,')');
            elseif xvalue == "Fuel Mass"
                app.plt.xlabel = append('Mass (',app.XaxisunitsDropDown.Value,')');
            elseif xvalue == "Total Mass"
                app.plt.xlabel = append('Mass (',app.XaxisunitsDropDown.Value,')');
            elseif xvalue == "Center of Mass"
                app.plt.xlabel = append('Center of Mass (',app.XaxisunitsDropDown.Value,')');
            elseif xvalue == "Chamber Pressure"
                app.plt.xlabel = append('Pressure (',app.XaxisunitsDropDown.Value,')');
            elseif xvalue == "Total Propellant Mass"
                app.plt.xlabel = append('Mass (',app.XaxisunitsDropDown.Value,')');
            elseif xvalue == "Tank Pressure"
                app.plt.xlabel = append('Pressure (',app.XaxisunitsDropDown.Value,')');
            elseif xvalue == "Oxidizer Mass Flow Rate"
                app.plt.xlabel = append('Mass Flow Rate (',app.XaxisunitsDropDown.Value,')');
            elseif xvalue == "Fuel Mass Flow Rate"
                app.plt.xlabel = append('Mass Flow Rate (',app.XaxisunitsDropDown.Value,')');
            elseif xvalue == "Total Mass Flow Rate"
                app.plt.xlabel = append('Mass Flow Rate (',app.XaxisunitsDropDown.Value,')');
            elseif xvalue == "OF Ratio"
                app.plt.xlabel = 'OF Ratio';
            elseif xvalue == "Regression Rate"
                app.plt.xlabel = append('Regression Rate (',app.XaxisunitsDropDown.Value,')');
            elseif xvalue == "Thrust"
                app.plt.xlabel = append('Thrust (',app.XaxisunitsDropDown.Value,')');
            elseif xvalue == "Injector Pressure Drop"
                app.plt.xlabel = append('Pressure (',app.XaxisunitsDropDown.Value,')');
            end
            
            yvalue = app.YaxisDropDown.Value;
            if yvalue == "Time"
                app.plt.ylabel = append('Time (',app.YaxisunitsDropDown.Value,')');
            elseif yvalue == "Oxidizer Mass"
                app.plt.ylabel = append('Mass (',app.YaxisunitsDropDown.Value,')');
            elseif yvalue == "Fuel Mass"
                app.plt.ylabel = append('Mass (',app.YaxisunitsDropDown.Value,')');
            elseif yvalue == "Total Mass"
                app.plt.ylabel = append('Mass (',app.YaxisunitsDropDown.Value,')');
            elseif yvalue == "Center of Mass"
                app.plt.ylabel = append('Center of Mass (',app.YaxisunitsDropDown.Value,')');
            elseif yvalue == "Chamber Pressure"
                app.plt.ylabel = append('Pressure (',app.YaxisunitsDropDown.Value,')');
            elseif yvalue == "Total Propellant Mass"
                app.plt.ylabel = append('Mass (',app.YaxisunitsDropDown.Value,')');
            elseif yvalue == "Tank Pressure"
                app.plt.ylabel = append('Pressure (',app.YaxisunitsDropDown.Value,')');
            elseif yvalue == "Oxidizer Mass Flow Rate"
                app.plt.ylabel = append('Mass Flow Rate (',app.YaxisunitsDropDown.Value,')');
            elseif yvalue == "Fuel Mass Flow Rate"
                app.plt.ylabel = append('Mass Flow Rate (',app.YaxisunitsDropDown.Value,')');
            elseif yvalue == "Total Mass Flow Rate"
                app.plt.ylabel = append('Mass Flow Rate (',app.YaxisunitsDropDown.Value,')');
            elseif yvalue == "OF Ratio"
                app.plt.ylabel = 'OF Ratio';
            elseif yvalue == "Regression Rate"
                app.plt.ylabel = append('Regression Rate (',app.YaxisunitsDropDown.Value,')');
            elseif yvalue == "Thrust"
                app.plt.ylabel = append('Thrust (',app.YaxisunitsDropDown.Value,')');
            elseif yvalue == "Injector Pressure Drop"
                app.plt.ylabel = append('Pressure (',app.YaxisunitsDropDown.Value,')');
            end
            
            app.UIAxes.XLabel.String = app.plt.xlabel;
            app.UIAxes.YLabel.String = app.plt.ylabel;
            hold(app.UIAxes,"on")
            legend(app.UIAxes)
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
             [file,path] = uiputfile('*.png','Save Plot',sprintf('%s\\output\\%s.png',app.app_dir,app.PlotTitleEditField.Value));

            if isequal(file,0)
            else
                filepath = convertCharsToStrings(fullfile(path,file));
                exportgraphics(app.UIAxes,filepath);
            end
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: ExportRSEButton
        function ExportRSEButtonPushed(app, event)
            if app.IncludeCenterofMassSimulationCheckBox.Value == 1
                %Input template
                f = app.output.F_rocket; %array of force (newtons) as function of time
                m = app.output.m_motor/1000; %array of mass (grams) as function of time
                t = app.output.t; %array of time (seconds) for other variables
                Itot = trapz(app.output.t(app.output.F_rocket>0),app.output.F_rocket(app.output.F_rocket>0)); %total impulse, newton-seconds
                propWt = ((app.output.m_fuel(1)-app.output.m_fuel(end)) + (app.output.m_ox_total(1)-app.output.m_ox_total(end)))/1000; %propellant weight, grams
                Isp = Itot/((propWt*1000)*9.81); %specific impulse, seconds
                Type = 'Hybrid';
                avgThrust = mean(app.output.F_rocket); %average thrust, newtons
                burn_time = max(app.output.t); %burn time, seconds
                motorClass = impulse(Itot);
                prompt = {'Enter Motor Diameter in mm:','Enter Motor Length in mm:','Enter Motor Manufacturer:','Input Motor Notes:'};
                dlgtitle = 'RSE Export';
                dims = [1 12; 1 12; 1 50; 5 50];
                definput = {'0','0','Research',''};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                code = sprintf('%s%0.0f',motorClass,avgThrust);
                dia = str2double(answer{1}); %motor diameter, millimeters
                exitDia = (app.NozzleThroatDiameterEditField_2.Value*app.NozzleExpansionRatioEditField_2.Value^0.5)*1000; %nozzle exit diameter, millimeters
                initWt = app.output.m_motor(1)/1000; %loaded motor weight before ignition, grams
                len = str2double(answer{2}); %motor length, millimeters
                cg = len - (app.output.cg_motor/1000); %array of cg (millimeters from forward end of motor) as function of time
                massFrac = (app.output.m_motor(1)-app.output.m_motor(length(app.output.m_motor)))/app.output.m_motor(1); %mass fraction, ratio of propellant mass to total mass
                mfg = answer{3};
                peakThrust = max(app.output.F_rocket); %peak thrust, newtons
                throatDia = app.NozzleThroatDiameterEditField_2.Value*1000;
                comments = answer{4};

                [file,path] = uiputfile('*.rse','Save RSE File',sprintf('%s\\output\\%s.rse',app.app_dir,code));

                if isequal(file,0)
                else
                    fileID = fopen(convertCharsToStrings(fullfile(path,file)),'w');
                    fprintf(fileID,'<engine-database>\n	<engine-list>\n');
                    fprintf(fileID,'	<engine FDiv="10" FFix="1" FStep="-1." Isp="%f" Itot="%f" Type="%s" auto-calc-cg="0" auto-calc-mass="0" avgThrust="%f" burn-time="%f" cgDiv="10" cgFix="1" cgStep="-1." code="%s" delays="0" dia="%f" exitDia="%f" initWt="%f" len="%f" mDiv="10" mFix="1" mStep="-1." massFrac="%f" mfg="%s" peakThrust="%f" propWt="%f" tDiv="10" tFix="1" tStep="-1." throatDia="%f">',Isp, Itot, Type, avgThrust, burn_time, code, dia, exitDia, initWt, len, massFrac, mfg, peakThrust, propWt, throatDia);
                    fprintf(fileID,'\n	<comments> \n');
                    fprintf(fileID,'		%s\n',comments);
                    fprintf(fileID,'	</comments>\n');
                    fprintf(fileID,'	<data>\n');
            
                    for i = 1:length(cg)
                        fprintf(fileID,'		<eng-data cg="%f" f="%f" m="%f" t="%f"/>\n',cg(i),f(i),m(i),t(i));
                    end
            
                    fprintf(fileID,'	</data>\n');
                    fprintf(fileID,'	</engine>\n');
                    fprintf(fileID,' 	</engine-list>\n');
                    fprintf(fileID,'</engine-database>\n');
                    fclose('all');
                end
            end
        end

        % Button pushed function: ExportCSVButton
        function ExportCSVButtonPushed(app, event)
            [file,path] = uiputfile('*.csv','Save CSV Output',sprintf('%s\\output\\HRAP_output.csv',app.app_dir));

            if isequal(file,0)
            else
                filename = convertCharsToStrings(fullfile(path,file));
                if app.IncludeCenterofMassSimulationCheckBox == 1
                    M = ["Time (s)","Thrust (N)","Oxidizer Tank Pressure (kPa)","Combustion Chamber Pressure (kPa)","Injector Pressure Drop (kPa)","Oxidizer Mass (kg)","Fuel Mass (kg)","Total Motor Mass (kg)","Oxidizer Mass Flow Rate (kg/s)","Fuel Mass Flow Rate (kg/s)","Exhaust Mass Flow Rate (kg/s)","Oxidizer to Fuel Ratio","Grain ID (m)","Regression Rate (m/s)","Motor Center of Mass (m)";
                        app.output.t, app.output.F_rocket, app.output.P_tank./1000, app.output.P_cmbr./1000, app.output.delta_P_tank./1000, app.output.m_ox_total, app.output.m_fuel, app.output.m_motor, app.output.m_dot_inj, app.output.m_dot_fuel, app.output.m_dot_noz, app.output.OF, app.output.id_grn, app.output.r_dot_grn, app.output.cg_motor];
                else
                    M = ["Time (s)","Thrust (N)","Oxidizer Tank Pressure (kPa)","Combustion Chamber Pressure (kPa)","Injector Pressure Drop (kPa)","Oxidizer Mass (kg)","Fuel Mass (kg)","Oxidizer Mass Flow Rate (kg/s)","Fuel Mass Flow Rate (kg/s)","Exhaust Mass Flow Rate (kg/s)","Oxidizer to Fuel Ratio","Grain ID (m)","Regression Rate (m/s)";
                        app.output.t, app.output.F_rocket, app.output.P_tank./1000, app.output.P_cmbr./1000, app.output.delta_P_tank./1000, app.output.m_ox_total, app.output.m_fuel, app.output.m_dot_inj, app.output.m_dot_fuel, app.output.m_dot_noz, app.output.OF, app.output.id_grn, app.output.r_dot_grn];
                end
                
                writematrix(M,filename);
                fclose('all');
            end
            
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            totalImpulse = trapz(app .output.t(app .output.F_rocket>0),app .output.F_rocket(app .output.F_rocket>0));
            [motorClass,percent] = impulse(totalImpulse);
            burnTime = app .output.t(sum(app .output.F_rocket>0));
            peakF_rocket = max(app .output.F_rocket);
            averageF_rocket = mean(app .output.F_rocket(app .output.F_rocket>0));
            peakPressure = max(app .output.P_cmbr)/10^5;
            averagePressure = mean(app .output.P_cmbr(app .output.P_cmbr>0))/10^5;
            fuelConsumed = (app .output.m_fuel(1)-app .output.m_fuel(end));
            oxidizerConsumed = (app .output.m_ox_total(1)-app .output.m_ox_total(end));
            averageOF = oxidizerConsumed/fuelConsumed;
            intPressure = trapz(app .output.t,app .output.P_cmbr);
            Cstar = intPressure*0.25*pi*app.NozzleThroatDiameterEditField_2.Value^2/(fuelConsumed+oxidizerConsumed);
            specificImpulse = totalImpulse/(((oxidizerConsumed+fuelConsumed))*9.81);
            fuelLeft = app.output.id_grn(end)*100;

            [file,path] = uiputfile('*.txt','Save Results Summary',sprintf('%s\\output\\sim_results.txt',app.app_dir));

            if isequal(file,0)
            else
                fileID = fopen(convertCharsToStrings(fullfile(path,file)),'w');
                fprintf(fileID,'Motor Name: %s\n    Propellant: %s\n    Oxidizer Tank Volume: %0.0f cc\n    Burn Time: %0.3f s\n    Peak Thrust: %0.1f N\n    Average Thrust: %0.1f N\n    Total Impulse: %0.2f N-s\n    Peak Chamber Pressure: %0.3f bar\n    Average Chamber Pressure: %0.3f bar\n    Port Diameter at Burnout: %0.3f cm\n    Fuel Consumed: %0.3f kg\n    Oxidizer Consumed: %0.3f kg\n    Average OF Ratio: %0.3f\n    Characteristic Velocity: %0.1f m/s\n    Specific Impulse: %0.1f s\n    Motor Classification: %3.0f%% %s%0.0f', app.ConfigurationNameEditField.Value,app.fuel_file,app.TankVolumeEditField_2.Value*10^6,burnTime,peakF_rocket,averageF_rocket,totalImpulse,peakPressure,averagePressure,fuelLeft,fuelConsumed,oxidizerConsumed,averageOF,Cstar,specificImpulse,percent,motorClass,averageF_rocket);
                fclose('all');
            end        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationSetupTab = uitab(app.TabGroup);
            app.SimulationSetupTab.Title = 'Simulation Setup';
            app.SimulationParametersPanel = uipanel(app.SimulationSetupTab);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.MaximumSimulationTimeEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.MaximumSimulationTimeEditFieldLabel.Text = 'Maximum Simulation Time';
            app.MaximumSimulationTimeEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.MaximumSimulationTimeEditField.Value = 20;
            app.SimulationTypeDropDownLabel = uilabel(app.SimulationParametersPanel);
            app.SimulationTypeDropDownLabel.Text = 'Simulation Type';
            app.SimulationTypeDropDown = uidropdown(app.SimulationParametersPanel);
            app.SimulationTypeDropDown.Items = {'Hot Fire', 'Cold Flow'};
            app.SimulationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationTypeDropDownValueChanged, true);
            app.SimulationTypeDropDown.Value = 'Hot Fire';
            app.SimulationTimeStepsEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.SimulationTimeStepsEditFieldLabel.Text = 'Simulation Time Steps';
            app.SimulationTimeStepsEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.SimulationTimeStepsEditField.Value = 0.001;
            app.sLabel = uilabel(app.SimulationParametersPanel);
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.SimulationParametersPanel);
            app.sLabel_2.Text = 's';
            app.AtmosphericPropertiesPanel = uipanel(app.SimulationSetupTab);
            app.AtmosphericPropertiesPanel.Title = 'Atmospheric Properties';
            app.DropDown_6 = uidropdown(app.AtmosphericPropertiesPanel);
            app.DropDown_6.Items = {'Pa', 'kPa', 'MPa', 'Atm', 'Bar', 'psi', 'psf'};
            app.DropDown_6.Editable = 'on';
            app.DropDown_6.Value = 'Pa';
            app.AmbientPressureEditFieldLabel = uilabel(app.AtmosphericPropertiesPanel);
            app.AmbientPressureEditFieldLabel.Text = 'Ambient Pressure';
            app.AmbientPressureEditField = uieditfield(app.AtmosphericPropertiesPanel, 'numeric');
            app.AmbientPressureEditField.Value = 101325;
            app.DropDown_7 = uidropdown(app.AtmosphericPropertiesPanel);
            app.DropDown_7.Items = {'K', 'C', 'R', 'F'};
            app.DropDown_7.Editable = 'on';
            app.DropDown_7.Value = 'K';
            app.AmbientTemperatureEditFieldLabel = uilabel(app.AtmosphericPropertiesPanel);
            app.AmbientTemperatureEditFieldLabel.Text = 'Ambient Temperature';
            app.AmbientTemperatureEditField = uieditfield(app.AtmosphericPropertiesPanel, 'numeric');
            app.AmbientTemperatureEditField.Value = 293.15;
            app.DropDown_8 = uidropdown(app.AtmosphericPropertiesPanel);
            app.DropDown_8.Items = {'kg/m^3', 'g/cm^3', 'lbm/in^3', 'lbm/ft^3'};
            app.DropDown_8.Editable = 'on';
            app.DropDown_8.Value = 'kg/m^3';
            app.AmbientAirDensityEditFieldLabel = uilabel(app.AtmosphericPropertiesPanel);
            app.AmbientAirDensityEditFieldLabel.Text = 'Ambient Air Density';
            app.AmbientAirDensityEditField = uieditfield(app.AtmosphericPropertiesPanel, 'numeric');
            app.AmbientAirDensityEditField.Value = 1.225;
            app.TankPropertiesPanel = uipanel(app.SimulationSetupTab);
            app.TankPropertiesPanel.Title = 'Tank Properties';
            app.ThermodymenicTankModelDropDownLabel = uilabel(app.TankPropertiesPanel);
            app.ThermodymenicTankModelDropDownLabel.Text = 'Thermodymenic Tank Model';
            app.ThermodymenicTankModelDropDown = uidropdown(app.TankPropertiesPanel);
            app.ThermodymenicTankModelDropDown.Items = {'HRAP (Original)', 'HRAP (Remastered)', 'Internal Energy'};
            app.ThermodymenicTankModelDropDown.Value = 'HRAP (Original)';
            app.OxidizerDropDown_2Label = uilabel(app.TankPropertiesPanel);
            app.OxidizerDropDown_2Label.Text = 'Oxidizer';
            app.OxidizerDropDown_2 = uidropdown(app.TankPropertiesPanel);
            app.OxidizerDropDown_2.Items = {'NitrousOxide', 'CarbonDioxide'};
            app.OxidizerDropDown_2.Value = 'NitrousOxide';
            app.DropDown_9 = uidropdown(app.TankPropertiesPanel);
            app.DropDown_9.Items = {'m^3', 'cm^3', 'L', 'Gal', 'in^3', 'ft^3'};
            app.DropDown_9.Editable = 'on';
            app.DropDown_9.Value = 'm^3';
            app.TankVolumeEditField_2Label = uilabel(app.TankPropertiesPanel);
            app.TankVolumeEditField_2Label.Text = 'Tank Volume';
            app.TankVolumeEditField_2 = uieditfield(app.TankPropertiesPanel, 'numeric');
            app.DropDown_10 = uidropdown(app.TankPropertiesPanel);
            app.DropDown_10.Items = {'kg', 'g', 'oz', 'lbm'};
            app.DropDown_10.Editable = 'on';
            app.DropDown_10.Value = 'kg';
            app.OxidizerMassEditField_2Label = uilabel(app.TankPropertiesPanel);
            app.OxidizerMassEditField_2Label.Text = 'Oxidizer Mass';
            app.OxidizerMassEditField_2 = uieditfield(app.TankPropertiesPanel, 'numeric');
            app.DropDown_38 = uidropdown(app.TankPropertiesPanel);
            app.DropDown_38.Items = {'K', 'C', 'R', 'F'};
            app.DropDown_38.Editable = 'on';
            app.DropDown_38.Value = 'K';
            app.OxidizerTemperatureEditFieldLabel = uilabel(app.TankPropertiesPanel);
            app.OxidizerTemperatureEditFieldLabel.Text = 'Oxidizer Temperature';
            app.OxidizerTemperatureEditField = uieditfield(app.TankPropertiesPanel, 'numeric');
            app.ConfigurationNameEditFieldLabel = uilabel(app.SimulationSetupTab);
            app.ConfigurationNameEditFieldLabel.Text = 'Configuration Name';
            app.ConfigurationNameEditField = uieditfield(app.SimulationSetupTab, 'text');
            app.VentPropertiesPanel = uipanel(app.SimulationSetupTab);
            app.VentPropertiesPanel.Title = 'Vent Properties';
            app.VentStateDropDownLabel = uilabel(app.VentPropertiesPanel);
            app.VentStateDropDownLabel.Text = 'Vent State';
            app.VentStateDropDown = uidropdown(app.VentPropertiesPanel);
            app.VentStateDropDown.Items = {'No Vent', 'Vent to Atmosphere', 'Vent to Chamber'};
            app.VentStateDropDown.ValueChangedFcn = createCallbackFcn(app, @VentStateDropDownValueChanged, true);
            app.VentStateDropDown.Value = 'No Vent';
            app.VentModelDropDown_2Label = uilabel(app.VentPropertiesPanel);
            app.VentModelDropDown_2Label.Enable = 'off';
            app.VentModelDropDown_2Label.Text = 'Vent Model';
            app.VentModelDropDown_2 = uidropdown(app.VentPropertiesPanel);
            app.VentModelDropDown_2.Items = {'Single Phase Incompressible', 'Perfect Gas Compressible'};
            app.VentModelDropDown_2.Enable = 'off';
            app.VentModelDropDown_2.Value = 'Single Phase Incompressible';
            app.DropDown_12 = uidropdown(app.VentPropertiesPanel);
            app.DropDown_12.Items = {'m', 'cm', 'mm', 'in', 'ft'};
            app.DropDown_12.Editable = 'on';
            app.DropDown_12.Enable = 'off';
            app.DropDown_12.Value = 'm';
            app.VentDiameterEditField_2Label = uilabel(app.VentPropertiesPanel);
            app.VentDiameterEditField_2Label.Enable = 'off';
            app.VentDiameterEditField_2Label.Text = 'Vent Diameter';
            app.VentDiameterEditField_2 = uieditfield(app.VentPropertiesPanel, 'numeric');
            app.VentDiameterEditField_2.Enable = 'off';
            app.VentDischargeCoefficientEditFieldLabel = uilabel(app.VentPropertiesPanel);
            app.VentDischargeCoefficientEditFieldLabel.Enable = 'off';
            app.VentDischargeCoefficientEditFieldLabel.Text = 'Vent Discharge Coefficient';
            app.VentDischargeCoefficientEditField = uieditfield(app.VentPropertiesPanel, 'numeric');
            app.VentDischargeCoefficientEditField.Enable = 'off';
            app.VentDischargeCoefficientEditField.Value = 1;
            app.InjectorPropertiesPanel_2 = uipanel(app.SimulationSetupTab);
            app.InjectorPropertiesPanel_2.Title = 'Injector Properties';
            app.InjectorLiquidModelDropDownLabel = uilabel(app.InjectorPropertiesPanel_2);
            app.InjectorLiquidModelDropDownLabel.Text = 'Injector Liquid Model';
            app.InjectorLiquidModelDropDown = uidropdown(app.InjectorPropertiesPanel_2);
            app.InjectorLiquidModelDropDown.Items = {'Single Phase Incompressible', 'Homogeneus Equilibrium', 'Dyer Model'};
            app.InjectorLiquidModelDropDown.Value = 'Single Phase Incompressible';
            app.InjectorVaporModelDropDownLabel = uilabel(app.InjectorPropertiesPanel_2);
            app.InjectorVaporModelDropDownLabel.Text = 'Injector Vapor Model';
            app.InjectorVaporModelDropDown = uidropdown(app.InjectorPropertiesPanel_2);
            app.InjectorVaporModelDropDown.Items = {'Single Phase Incompressible', 'Perfect Gas Compressible'};
            app.InjectorVaporModelDropDown.Value = 'Single Phase Incompressible';
            app.NumberofInjectorOrificesEditField_2Label = uilabel(app.InjectorPropertiesPanel_2);
            app.NumberofInjectorOrificesEditField_2Label.Text = 'Number of Injector Orifices';
            app.NumberofInjectorOrificesEditField_2 = uieditfield(app.InjectorPropertiesPanel_2, 'numeric');
            app.NumberofInjectorOrificesEditField_2.Value = 1;
            app.DropDown_15 = uidropdown(app.InjectorPropertiesPanel_2);
            app.DropDown_15.Items = {'m', 'cm', 'mm', 'in', 'ft'};
            app.DropDown_15.Editable = 'on';
            app.DropDown_15.Value = 'm';
            app.InjectorDiameterEditFieldLabel = uilabel(app.InjectorPropertiesPanel_2);
            app.InjectorDiameterEditFieldLabel.Text = 'Injector Diameter';
            app.InjectorDiameterEditField = uieditfield(app.InjectorPropertiesPanel_2, 'numeric');
            app.InjectorDischargeCoefficientEditField_2Label = uilabel(app.InjectorPropertiesPanel_2);
            app.InjectorDischargeCoefficientEditField_2Label.Text = 'Injector Discharge Coefficient';
            app.InjectorDischargeCoefficientEditField_2 = uieditfield(app.InjectorPropertiesPanel_2, 'numeric');
            app.InjectorDischargeCoefficientEditField_2.Value = 1;
            app.ChamberPropertiesPanel_2 = uipanel(app.SimulationSetupTab);
            app.ChamberPropertiesPanel_2.Title = 'Chamber Properties';
            app.RegressionModelDropDown_2Label = uilabel(app.ChamberPropertiesPanel_2);
            app.RegressionModelDropDown_2Label.Text = 'Regression Model';
            app.RegressionModelDropDown_2 = uidropdown(app.ChamberPropertiesPanel_2);
            app.RegressionModelDropDown_2.Items = {'Constant OF', 'Shifting OF'};
            app.RegressionModelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @RegressionModelDropDown_2ValueChanged, true);
            app.RegressionModelDropDown_2.Value = 'Constant OF';
            app.DropDown_17 = uidropdown(app.ChamberPropertiesPanel_2);
            app.DropDown_17.Items = {'m^3', 'cm^3', 'L', 'Gal', 'in^3', 'ft^3'};
            app.DropDown_17.Editable = 'on';
            app.DropDown_17.Value = 'm^3';
            app.EmptyChamberVolumeEditField_2Label = uilabel(app.ChamberPropertiesPanel_2);
            app.EmptyChamberVolumeEditField_2Label.Text = 'Empty Chamber Volume';
            app.EmptyChamberVolumeEditField_2 = uieditfield(app.ChamberPropertiesPanel_2, 'numeric');
            app.DropDown_18 = uidropdown(app.ChamberPropertiesPanel_2);
            app.DropDown_18.Items = {'Pa', 'kPa', 'MPa', 'Atm', 'Bar', 'psi', 'psf'};
            app.DropDown_18.Editable = 'on';
            app.DropDown_18.Value = 'Pa';
            app.InitialChamberPressureEditFieldLabel = uilabel(app.ChamberPropertiesPanel_2);
            app.InitialChamberPressureEditFieldLabel.Text = 'Initial Chamber Pressure';
            app.InitialChamberPressureEditField = uieditfield(app.ChamberPropertiesPanel_2, 'numeric');
            app.InitialChamberPressureEditField.Value = 101325;
            app.DropDown_19 = uidropdown(app.ChamberPropertiesPanel_2);
            app.DropDown_19.Items = {'m', 'cm', 'mm', 'in', 'ft'};
            app.DropDown_19.Editable = 'on';
            app.DropDown_19.Value = 'm';
            app.FuelGrainInnerDiameterEditFieldLabel = uilabel(app.ChamberPropertiesPanel_2);
            app.FuelGrainInnerDiameterEditFieldLabel.Text = 'Fuel Grain Inner Diameter';
            app.FuelGrainInnerDiameterEditField = uieditfield(app.ChamberPropertiesPanel_2, 'numeric');
            app.DropDown_20 = uidropdown(app.ChamberPropertiesPanel_2);
            app.DropDown_20.Items = {'m', 'cm', 'mm', 'in', 'ft'};
            app.DropDown_20.Editable = 'on';
            app.DropDown_20.Value = 'm';
            app.FuelGrainOuterDiameterEditFieldLabel = uilabel(app.ChamberPropertiesPanel_2);
            app.FuelGrainOuterDiameterEditFieldLabel.Text = 'Fuel Grain Outer Diameter';
            app.FuelGrainOuterDiameterEditField = uieditfield(app.ChamberPropertiesPanel_2, 'numeric');
            app.DropDown_21 = uidropdown(app.ChamberPropertiesPanel_2);
            app.DropDown_21.Items = {'m', 'cm', 'mm', 'in', 'ft'};
            app.DropDown_21.Editable = 'on';
            app.DropDown_21.Value = 'm';
            app.FuelGrainLenghtEditFieldLabel = uilabel(app.ChamberPropertiesPanel_2);
            app.FuelGrainLenghtEditFieldLabel.Text = 'Fuel Grain Lenght';
            app.FuelGrainLenghtEditField = uieditfield(app.ChamberPropertiesPanel_2, 'numeric');
            app.CombustionEfficiencyCEditField_2Label = uilabel(app.ChamberPropertiesPanel_2);
            app.CombustionEfficiencyCEditField_2Label.Text = 'Combustion Efficiency (C*)';
            app.CombustionEfficiencyCEditField_2 = uieditfield(app.ChamberPropertiesPanel_2, 'numeric');
            app.CombustionEfficiencyCEditField_2.Value = 100;
            app.DropDown_23 = uidropdown(app.ChamberPropertiesPanel_2);
            app.DropDown_23.Items = {'g/cm^3', 'kg/m^3', 'lbm/in^3', 'lbm/ft^3'};
            app.DropDown_23.Editable = 'on';
            app.DropDown_23.Value = 'kg/m^3';
            app.FuelDensityEditField_2Label = uilabel(app.ChamberPropertiesPanel_2);
            app.FuelDensityEditField_2Label.Text = 'Fuel Density';
            app.FuelDensityEditField_2 = uieditfield(app.ChamberPropertiesPanel_2, 'numeric');
            app.ConstantOFRatioEditField_2Label = uilabel(app.ChamberPropertiesPanel_2);
            app.ConstantOFRatioEditField_2Label.Text = 'Constant OF Ratio';
            app.ConstantOFRatioEditField_2 = uieditfield(app.ChamberPropertiesPanel_2, 'numeric');
            app.ConstantOFRatioEditField_2.Value = 1;
            app.RegressionCoefficientaEditField_2Label = uilabel(app.ChamberPropertiesPanel_2);
            app.RegressionCoefficientaEditField_2Label.Enable = 'off';
            app.RegressionCoefficientaEditField_2Label.Text = 'Regression Coefficient  (a)';
            app.RegressionCoefficientaEditField_2 = uieditfield(app.ChamberPropertiesPanel_2, 'numeric');
            app.RegressionCoefficientaEditField_2.Enable = 'off';
            app.RegressionExponentnEditField_2Label = uilabel(app.ChamberPropertiesPanel_2);
            app.RegressionExponentnEditField_2Label.Enable = 'off';
            app.RegressionExponentnEditField_2Label.Text = 'Regression Exponent (n)';
            app.RegressionExponentnEditField_2 = uieditfield(app.ChamberPropertiesPanel_2, 'numeric');
            app.RegressionExponentnEditField_2.Enable = 'off';
            app.LengthExponentmEditField_2Label = uilabel(app.ChamberPropertiesPanel_2);
            app.LengthExponentmEditField_2Label.Enable = 'off';
            app.LengthExponentmEditField_2Label.Text = 'Length Exponent (m)';
            app.LengthExponentmEditField_2 = uieditfield(app.ChamberPropertiesPanel_2, 'numeric');
            app.LengthExponentmEditField_2.Enable = 'off';
            app.Label = uilabel(app.ChamberPropertiesPanel_2);
            app.Label.Text = '%';
            app.LoadFuelConfigButton = uibutton(app.ChamberPropertiesPanel_2, 'push');
            app.LoadFuelConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFuelConfigButtonPushed, true);
            app.LoadFuelConfigButton.Text = 'Load Fuel Config';
            app.NoFileSelectedLabel = uilabel(app.ChamberPropertiesPanel_2);
            app.NoFileSelectedLabel.Text = 'No File Selected';
            app.NozzlePropertiesPanel_2 = uipanel(app.SimulationSetupTab);
            app.NozzlePropertiesPanel_2.Title = 'Nozzle Properties';
            app.NozzleModelDropDown_2Label = uilabel(app.NozzlePropertiesPanel_2);
            app.NozzleModelDropDown_2Label.Text = 'Nozzle Model';
            app.NozzleModelDropDown_2 = uidropdown(app.NozzlePropertiesPanel_2);
            app.NozzleModelDropDown_2.Items = {'1D', '2D'};
            app.NozzleModelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @NozzleModelDropDown_2ValueChanged, true);
            app.NozzleModelDropDown_2.Value = '1D';
            app.ConicalNozzleHalfAngleLabel_2 = uilabel(app.NozzlePropertiesPanel_2);
            app.ConicalNozzleHalfAngleLabel_2.Enable = 'off';
            app.ConicalNozzleHalfAngleLabel_2.Text = 'Conical Nozzle Half Angle (α)';
            app.ConicalNozzleHalfAngleEditField_2 = uieditfield(app.NozzlePropertiesPanel_2, 'numeric');
            app.ConicalNozzleHalfAngleEditField_2.Enable = 'off';
            app.DropDown_29 = uidropdown(app.NozzlePropertiesPanel_2);
            app.DropDown_29.Items = {'m', 'cm', 'mm', 'in', 'ft'};
            app.DropDown_29.Editable = 'on';
            app.DropDown_29.Value = 'm';
            app.NozzleThroatDiameterEditField_2Label = uilabel(app.NozzlePropertiesPanel_2);
            app.NozzleThroatDiameterEditField_2Label.Text = 'Nozzle Throat Diameter';
            app.NozzleThroatDiameterEditField_2 = uieditfield(app.NozzlePropertiesPanel_2, 'numeric');
            app.NozzleExpansionRatioEditField_2Label = uilabel(app.NozzlePropertiesPanel_2);
            app.NozzleExpansionRatioEditField_2Label.Text = 'Nozzle Expansion Ratio';
            app.NozzleExpansionRatioEditField_2 = uieditfield(app.NozzlePropertiesPanel_2, 'numeric');
            app.NozzleEfficiencyEditField_2Label = uilabel(app.NozzlePropertiesPanel_2);
            app.NozzleEfficiencyEditField_2Label.Text = 'Nozzle Efficiency';
            app.NozzleEfficiencyEditField_2 = uieditfield(app.NozzlePropertiesPanel_2, 'numeric');
            app.NozzleEfficiencyEditField_2.Value = 100;
            app.NozzleDischargeCoefficientEditField_2Label = uilabel(app.NozzlePropertiesPanel_2);
            app.NozzleDischargeCoefficientEditField_2Label.Text = 'Nozzle Discharge Coefficient';
            app.NozzleDischargeCoefficientEditField_2 = uieditfield(app.NozzlePropertiesPanel_2, 'numeric');
            app.NozzleDischargeCoefficientEditField_2.Value = 1;
            app.degLabel = uilabel(app.NozzlePropertiesPanel_2);
            app.degLabel.Enable = 'off';
            app.degLabel.Text = 'deg';
            app.Label_2 = uilabel(app.NozzlePropertiesPanel_2);
            app.Label_2.Text = '%';
            app.MassPropertiesMeasureFromNozzlePanel = uipanel(app.SimulationSetupTab);
            app.MassPropertiesMeasureFromNozzlePanel.Title = 'Mass Properties (Measure From Nozzle)';
            app.DropDown_33 = uidropdown(app.MassPropertiesMeasureFromNozzlePanel);
            app.DropDown_33.Items = {'kg', 'g', 'oz', 'lbm'};
            app.DropDown_33.Editable = 'on';
            app.DropDown_33.Enable = 'off';
            app.DropDown_33.Value = 'kg';
            app.MotorDryMassEditFieldLabel = uilabel(app.MassPropertiesMeasureFromNozzlePanel);
            app.MotorDryMassEditFieldLabel.Enable = 'off';
            app.MotorDryMassEditFieldLabel.Text = 'Motor Dry Mass';
            app.MotorDryMassEditField = uieditfield(app.MassPropertiesMeasureFromNozzlePanel, 'numeric');
            app.MotorDryMassEditField.Enable = 'off';
            app.DropDown_34 = uidropdown(app.MassPropertiesMeasureFromNozzlePanel);
            app.DropDown_34.Items = {'m', 'cm', 'mm', 'in', 'ft'};
            app.DropDown_34.Editable = 'on';
            app.DropDown_34.Enable = 'off';
            app.DropDown_34.Value = 'm';
            app.DistancetoMotorDryCGEditFieldLabel = uilabel(app.MassPropertiesMeasureFromNozzlePanel);
            app.DistancetoMotorDryCGEditFieldLabel.Enable = 'off';
            app.DistancetoMotorDryCGEditFieldLabel.Text = 'Distance to Motor Dry CG';
            app.DistancetoMotorDryCGEditField = uieditfield(app.MassPropertiesMeasureFromNozzlePanel, 'numeric');
            app.DistancetoMotorDryCGEditField.Enable = 'off';
            app.DropDown_35 = uidropdown(app.MassPropertiesMeasureFromNozzlePanel);
            app.DropDown_35.Items = {'m', 'cm', 'mm', 'in', 'ft'};
            app.DropDown_35.Editable = 'on';
            app.DropDown_35.Enable = 'off';
            app.DropDown_35.Value = 'm';
            app.DistancetoBottomofTankEditFieldLabel = uilabel(app.MassPropertiesMeasureFromNozzlePanel);
            app.DistancetoBottomofTankEditFieldLabel.Enable = 'off';
            app.DistancetoBottomofTankEditFieldLabel.Text = 'Distance to Bottom of Tank';
            app.DistancetoBottomofTankEditField = uieditfield(app.MassPropertiesMeasureFromNozzlePanel, 'numeric');
            app.DistancetoBottomofTankEditField.Enable = 'off';
            app.DropDown_36 = uidropdown(app.MassPropertiesMeasureFromNozzlePanel);
            app.DropDown_36.Items = {'m', 'cm', 'mm', 'in', 'ft'};
            app.DropDown_36.Editable = 'on';
            app.DropDown_36.Enable = 'off';
            app.DropDown_36.Value = 'm';
            app.TankInnerDiameterEditFieldLabel = uilabel(app.MassPropertiesMeasureFromNozzlePanel);
            app.TankInnerDiameterEditFieldLabel.Enable = 'off';
            app.TankInnerDiameterEditFieldLabel.Text = 'Tank Inner Diameter';
            app.TankInnerDiameterEditField = uieditfield(app.MassPropertiesMeasureFromNozzlePanel, 'numeric');
            app.TankInnerDiameterEditField.Enable = 'off';
            app.DropDown_37 = uidropdown(app.MassPropertiesMeasureFromNozzlePanel);
            app.DropDown_37.Items = {'m', 'cm', 'mm', 'in', 'ft'};
            app.DropDown_37.Editable = 'on';
            app.DropDown_37.Enable = 'off';
            app.DropDown_37.Value = 'm';
            app.DistancetoBottomofFuelGrainEditFieldLabel = uilabel(app.MassPropertiesMeasureFromNozzlePanel);
            app.DistancetoBottomofFuelGrainEditFieldLabel.Enable = 'off';
            app.DistancetoBottomofFuelGrainEditFieldLabel.Text = 'Distance to Bottom of Fuel Grain';
            app.DistancetoBottomofFuelGrainEditField = uieditfield(app.MassPropertiesMeasureFromNozzlePanel, 'numeric');
            app.DistancetoBottomofFuelGrainEditField.Enable = 'off';
            app.IncludeCenterofMassSimulationCheckBox = uicheckbox(app.MassPropertiesMeasureFromNozzlePanel);
            app.IncludeCenterofMassSimulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeCenterofMassSimulationCheckBoxValueChanged, true);
            app.IncludeCenterofMassSimulationCheckBox.Text = 'Include Center of Mass Simulation';
            app.RUNButton = uibutton(app.SimulationSetupTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.LoadConfigurationButton = uibutton(app.SimulationSetupTab, 'push');
            app.LoadConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigurationButtonPushed, true);
            app.LoadConfigurationButton.Text = 'Load Configuration';
            app.SaveConfigurationButton = uibutton(app.SimulationSetupTab, 'push');
            app.SaveConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigurationButtonPushed, true);
            app.SaveConfigurationButton.Text = 'Save Configuration';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UIAxes = uiaxes(app.ResultsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotTitleEditFieldLabel = uilabel(app.ResultsTab);
            app.PlotTitleEditFieldLabel.Enable = 'off';
            app.PlotTitleEditFieldLabel.Text = 'Plot Title';
            app.PlotTitleEditField = uieditfield(app.ResultsTab, 'text');
            app.PlotTitleEditField.Enable = 'off';
            app.PlotTitleEditField.Value = 'Thrust vs Time';
            app.XaxisDropDownLabel = uilabel(app.ResultsTab);
            app.XaxisDropDownLabel.Enable = 'off';
            app.XaxisDropDownLabel.Text = 'X - axis';
            app.XaxisDropDown = uidropdown(app.ResultsTab);
            app.XaxisDropDown.Items = {'Time', 'Oxidizer Mass', 'Fuel Mass', 'Total Motor Mass', 'Center of Mass', 'Chamber Pressure', 'Total Propellant Mass', 'Tank Pressure', 'Oxidizer Mass Flow Rate', 'Fuel Mass Flow Rate', 'Total Mass Flow Rate', 'OF Ratio', 'Regression Rate', 'Thrust', 'Injector Pressure Drop'};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.XaxisDropDown.Enable = 'off';
            app.XaxisDropDown.Value = 'Time';
            app.YaxisLabel = uilabel(app.ResultsTab);
            app.YaxisLabel.Enable = 'off';
            app.YaxisLabel.Text = 'Y - axis';
            app.YaxisDropDown = uidropdown(app.ResultsTab);
            app.YaxisDropDown.Items = {'Time', 'Oxidizer Mass', 'Fuel Mass', 'Total Propellant Mass', 'Total Motor Mass', 'Center of Mass', 'Chamber Pressure', 'Tank Pressure', 'Oxidizer Mass Flow Rate', 'Fuel Mass Flow Rate', 'Total Mass Flow Rate', 'OF Ratio', 'Regression Rate', 'Thrust', 'Injector Pressure Drop'};
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownValueChanged, true);
            app.YaxisDropDown.Enable = 'off';
            app.YaxisDropDown.Value = 'Thrust';
            app.XaxisunitsDropDownLabel = uilabel(app.ResultsTab);
            app.XaxisunitsDropDownLabel.Enable = 'off';
            app.XaxisunitsDropDownLabel.Text = 'X - axis units';
            app.XaxisunitsDropDown = uidropdown(app.ResultsTab);
            app.XaxisunitsDropDown.Items = {'s'};
            app.XaxisunitsDropDown.Enable = 'off';
            app.XaxisunitsDropDown.Value = 's';
            app.YaxisunitsDropDownLabel = uilabel(app.ResultsTab);
            app.YaxisunitsDropDownLabel.Enable = 'off';
            app.YaxisunitsDropDownLabel.Text = 'Y - axis units';
            app.YaxisunitsDropDown = uidropdown(app.ResultsTab);
            app.YaxisunitsDropDown.Items = {'N', 'lbf', 'kgf'};
            app.YaxisunitsDropDown.Enable = 'off';
            app.YaxisunitsDropDown.Value = 'N';
            app.LegendNameEditFieldLabel = uilabel(app.ResultsTab);
            app.LegendNameEditFieldLabel.Enable = 'off';
            app.LegendNameEditFieldLabel.Text = 'Legend Name';
            app.LegendNameEditField = uieditfield(app.ResultsTab, 'text');
            app.LegendNameEditField.Enable = 'off';
            app.LegendNameEditField.Value = 'Thrust';
            app.AddPlotButton = uibutton(app.ResultsTab, 'push');
            app.AddPlotButton.ButtonPushedFcn = createCallbackFcn(app, @AddPlotButtonPushed, true);
            app.AddPlotButton.Enable = 'off';
            app.AddPlotButton.Text = 'Add Plot';
            app.ClearPlotButton = uibutton(app.ResultsTab, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Enable = 'off';
            app.ClearPlotButton.Text = 'Clear Plot';
            app.SavePlotButton = uibutton(app.ResultsTab, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Enable = 'off';
            app.SavePlotButton.Text = 'Save Plot';
            app.ExportRSEButton = uibutton(app.ResultsTab, 'push');
            app.ExportRSEButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRSEButtonPushed, true);
            app.ExportRSEButton.Enable = 'off';
            app.ExportRSEButton.Text = 'Export .RSE';
            app.ExportCSVButton = uibutton(app.ResultsTab, 'push');
            app.ExportCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVButtonPushed, true);
            app.ExportCSVButton.Enable = 'off';
            app.ExportCSVButton.Text = 'Export .CSV';
            app.SaveResultsButton = uibutton(app.ResultsTab, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Enable = 'off';
            app.SaveResultsButton.Text = 'Save Results';
            app.PerformanceSummaryTextAreaLabel = uilabel(app.ResultsTab);
            app.PerformanceSummaryTextAreaLabel.Text = 'Performance Summary ';
            app.PerformanceSummaryTextArea = uitextarea(app.ResultsTab);
            app.PerformanceSummaryTextArea.Value = {'Run a simulation to see results'};
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.ThissimulatorwasdesignedLabel = uilabel(app.InfoTab);
            app.ThissimulatorwasdesignedLabel.Text = {'For any and all information on how to use this simulator and how this simulator works please read the "Theory of Operation" document located in the install folder.'; ''; 'For instructions on how to install please see the Readme.txt located in the install folder.'; ''; 'If you want more reading on the physiscs of the simulator and other hybrid rocket design stuff see ...\Reference Papers\PDF Paper Catalogue.txt'; ''; 'To use the simulator your matlab needs to be configured with a comaptible version of python see:'; 'https://www.mathworks.com/help/matlab/matlab_external/install-supported-python-implementation.html'; ''; 'If you encounter any issues or bugs feel free to reach me on Discord (Adrian C) or at adricomis570@gmail.com'};
            app.CarletonHybridRocketMotorSimulatorLabel = uilabel(app.UIFigure);
            app.CarletonHybridRocketMotorSimulatorLabel.Text = 'Carleton Hybrid Rocket Motor Simulator';
            app.Version320250322Label = uilabel(app.UIFigure);
            app.Version320250322Label.Text = 'Version 3 (2025/03/22)';
            app.AdrianComisso2025Label = uilabel(app.UIFigure);
            app.AdrianComisso2025Label.Text = 'Adrian Comisso - 2025';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end